classdef LinkAN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        BoneRotateTab                 matlab.ui.container.Tab
        LoadimagesButton              matlab.ui.control.Button
        DataSizeLabel                 matlab.ui.control.Label
        PreviewCropButton             matlab.ui.control.Button
        LoadDataButton                matlab.ui.control.Button
        EdgedetectionButton           matlab.ui.control.Button
        LinearfitButton               matlab.ui.control.Button
        ResampleXZButton              matlab.ui.control.Button
        ResampleYZButton              matlab.ui.control.Button
        EditField_2                   matlab.ui.control.EditField
        KeepButton                    matlab.ui.control.Button
        ClearButton                   matlab.ui.control.Button
        Slider_2                      matlab.ui.control.Slider
        EditField_3                   matlab.ui.control.NumericEditField
        EditField_4                   matlab.ui.control.NumericEditField
        EditField_5                   matlab.ui.control.NumericEditField
        EditField_6                   matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel       matlab.ui.control.Label
        ThresholdEditField            matlab.ui.control.NumericEditField
        EditField_7                   matlab.ui.control.NumericEditField
        EditField_8                   matlab.ui.control.NumericEditField
        XminLabel                     matlab.ui.control.Label
        XmaxLabel                     matlab.ui.control.Label
        YminLabel                     matlab.ui.control.Label
        YmaxLabel                     matlab.ui.control.Label
        EditField_9                   matlab.ui.control.NumericEditField
        CreateSlicesButton            matlab.ui.control.Button
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes4_3                     matlab.ui.control.UIAxes
        UIAxes4_5                     matlab.ui.control.UIAxes
        UIAxes4_6                     matlab.ui.control.UIAxes
        UIAxes4_7                     matlab.ui.control.UIAxes
        UIAxes4_8                     matlab.ui.control.UIAxes
        PercentfillTab                matlab.ui.container.Tab
        FilePathButton_8              matlab.ui.control.Button
        NoiseThresholdEditFieldLabel  matlab.ui.control.Label
        NoiseThresholdEditField       matlab.ui.control.NumericEditField
        BoneThresholdEditFieldLabel   matlab.ui.control.Label
        BoneThresholdEditField        matlab.ui.control.NumericEditField
        RegionofinterestSliderLabel   matlab.ui.control.Label
        RegionofinterestSlider        matlab.ui.control.Slider
        SaveButton                    matlab.ui.control.Button
        CalculateButton               matlab.ui.control.Button
        EditField_10                  matlab.ui.control.NumericEditField
        UIAxes9_2                     matlab.ui.control.UIAxes
        UIAxes9_3                     matlab.ui.control.UIAxes
        UIAxes9_4                     matlab.ui.control.UIAxes
        UIAxes9_5                     matlab.ui.control.UIAxes
        getVoxTab                     matlab.ui.container.Tab
        FilePathButton                matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        SliderLabel                   matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        ofmaxgreyscaleEditFieldLabel  matlab.ui.control.Label
        ofmaxgreyscaleEditField       matlab.ui.control.NumericEditField
        Label_2                       matlab.ui.control.Label
        ProgressLabel                 matlab.ui.control.Label
        GoButton                      matlab.ui.control.Button
        SliceSliderLabel              matlab.ui.control.Label
        SliceSlider                   matlab.ui.control.Slider
        NextSampleButton              matlab.ui.control.Button
        ThresholdMethodButtonGroup    matlab.ui.container.ButtonGroup
        ValuesliderButton             matlab.ui.control.RadioButton
        bysampleButton                matlab.ui.control.RadioButton
        bygroupButton                 matlab.ui.control.RadioButton
        ThresholdButton               matlab.ui.control.Button
        ThresholdValueLabel           matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        PixelNoiseEditFieldLabel      matlab.ui.control.Label
        PixelNoiseEditField           matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        getLinkTab                    matlab.ui.container.Tab
        FilePathButton_2              matlab.ui.control.Button
        Label_4                       matlab.ui.control.Label
        ProgressLabel_2               matlab.ui.control.Label
        GoButton_2                    matlab.ui.control.Button
        Label_7                       matlab.ui.control.Label
        SegBoneTab                    matlab.ui.container.Tab
        FilePathButton_3              matlab.ui.control.Button
        Label_8                       matlab.ui.control.Label
        ProgressLabel_3               matlab.ui.control.Label
        GoButton_3                    matlab.ui.control.Button
        Label_9                       matlab.ui.control.Label
        LinkHistTab                   matlab.ui.container.Tab
        FilePathButton_4              matlab.ui.control.Button
        Label_10                      matlab.ui.control.Label
        ProgressLabel_4               matlab.ui.control.Label
        GoButton_4                    matlab.ui.control.Button
        Label_11                      matlab.ui.control.Label
        OutputLATab                   matlab.ui.container.Tab
        FilePathButton_5              matlab.ui.control.Button
        Label_6                       matlab.ui.control.Label
        ProgressLabel_5               matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        cutoffSliderLabel             matlab.ui.control.Label
        cutoffSlider                  matlab.ui.control.Slider
        Label_5                       matlab.ui.control.Label
        GoButton_5                    matlab.ui.control.Button
        Label_12                      matlab.ui.control.Label
        DirectionButtonGroup          matlab.ui.container.ButtonGroup
        ForwardButton                 matlab.ui.control.RadioButton
        BackwardButton                matlab.ui.control.RadioButton
        getRUSTTab                    matlab.ui.container.Tab
        FilePathButton_6              matlab.ui.control.Button
        Label_13                      matlab.ui.control.Label
        UITable2                      matlab.ui.control.Table
        GoButton_6                    matlab.ui.control.Button
        getZrayTab                    matlab.ui.container.Tab
        FilePathButton_7              matlab.ui.control.Button
        Label_14                      matlab.ui.control.Label
        ZRayMethodButtonGroup         matlab.ui.container.ButtonGroup
        LongestContiguousButton       matlab.ui.control.RadioButton
        TotalButton                   matlab.ui.control.RadioButton
        RegionofInterestButtonGroup   matlab.ui.container.ButtonGroup
        TotalButton_3                 matlab.ui.control.RadioButton
        CallusButton                  matlab.ui.control.RadioButton
        CortexButton                  matlab.ui.control.RadioButton
        GoButton_7                    matlab.ui.control.Button
        UITable3                      matlab.ui.control.Table
    end

    
    properties (Access = private)
        slice=1 % Description
        dir % Description
        fN2 % Description
        Names % Description
        position % Description
        Thresh=0 % Description
        slideval % Description
        inval % Description
        rotatedbone % Description
        fN1 % Description
        Pixel_Noise=0 % Description
        bysample=0 % Description
        bound=0 
      
        bone % Description
        xmin % Description
        xmax % Description
        ymin % Description
        ymax % Description
        sl=1 % Description
        kept=[] % Description
        tbone % Description
        ttbone % Description
        centroid % Description
        fitobjectxz % Description
        fitobjectyz % Description
        rotated % Description
        rotated2 % Description
        mx1 % Description
        mx2 % Description
        U_lim % Description
        L_lim % Description
        roivalue=0 % Description
        matrix_out % Description
        zrayout % Description
        frnt % Description
        sde % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            app.slice = round(app.SliceSlider.Value);
            
                imshow(app.rotatedbone.slice(app.slice).pic,'Parent',app.UIAxes);
                imshow(bwareaopen(imgaussfilt(app.rotatedbone.slice(app.slice).pic,1)>app.Thresh,25),'Parent',app.UIAxes2);
            
        end

        % Button pushed function: FilePathButton
        function FilePathButtonPushed(app, event)
           % app.dir = uigetdir('C:\Users\');
           app.dir=uigetdir('C:\Users\yazan\Documents\Research\Thesis');
            dirListing = dir(app.dir);
            
            app.Label.Text=app.dir;
            drawnow
            for d = 3:length(dirListing)
            name{d-2}=dirListing(d).name;
            
            end
            
            for iii=1%:numel(name)


            fN1 = [];
            cname=char(name(iii));   
            subNames= fullfile(app.dir,cname);
            dirsub=dir(subNames);
            fN1= strvcat(fN1,subNames);
            
            app.fN1=fN1;
            
            [n l]=size(fN1);
            
              fN2=[];
              fname=[];
              for d = 3:length(dirsub)
                fname{d-2}=dirsub(d).name;
              end 
            for d=1:length(fname)
            app.Names= fullfile(fN1,char(fname(d)));
            app.fN2= strvcat(app.fN2,app.Names);
            end
            
            
            [num len]=size(app.fN2);
             
            
            % %% Load in images and place them into structure "ROTATEDBONE"
            for i = 1:num
             b=imread(app.fN2(i,:));
            app.rotatedbone.slice(i).pic=b;
            
            end
            end
            app.SliceSlider.Limits=[1,num]
            app.SliceSlider.Enable=1;
            app.position=1;
            imshow(app.rotatedbone.slice(1).pic,'Parent',app.UIAxes);
            
            if app.Names>1
                app.NextSampleButton.Enable=1;
            end
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.slideval = app.Slider.Value;
            
        end

        % Value changed function: ofmaxgreyscaleEditField
        function ofmaxgreyscaleEditFieldValueChanged(app, event)
            app.inval = app.ofmaxgreyscaleEditField.Value/100;
            
        end

        % Selection changed function: ThresholdMethodButtonGroup
        function ThresholdMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.ThresholdMethodButtonGroup.SelectedObject;


        end

        % Button pushed function: ThresholdButton
        function ThresholdButtonPushed(app, event)
            
            if app.ValuesliderButton.Value==1
                app.Thresh=app.slideval;
                app.bysample=0;
            
        elseif app.bysampleButton.Value==1
                app.bysample=1;
                for z=1:length(app.rotatedbone.slice)
                locs=find(imhist(app.rotatedbone.slice(z).pic)>50);
                thresh(z)=locs(end)+1;
                
                end
                    avegs=mean(thresh,'omitnan');
                  
                app.Thresh=app.inval*avegs;


        elseif app.bygroupButton.Value==1
                app.bysample=0;
                dirListing = dir(app.dir);
                for d = 3:length(dirListing)
            name{d-2}=dirListing(d).name;
            
            end
                for iii=1:numel(name)


            fN1 = [];
            cname=char(name(iii));   
            subNames= fullfile(app.dir,cname);
            dirsub=dir(subNames);
            fN1= strvcat(fN1,subNames);
            
            [n l]=size(fN1);
            
              fN2=[];
              fname=[];
              for d = 3:length(dirsub)
                fname{d-2}=dirsub(d).name;
              end 
            for d=1:length(fname)
            app.Names= fullfile(fN1,char(fname(d)));
            fN2= strvcat(app.fN2,app.Names);
            end
            
            
            [num len]=size(fN2);
             
            
            % %% Load in images and place them into structure "ROTATEDBONE"
            for i = 1:num
             b=imread(fN2(i,:));
            rotatedbone.slice(i).pic=b;
            
            end
            for z=1:length(rotatedbone.slice)
                locs=find(imhist(rotatedbone.slice(z).pic)>50);
                thresh(z)=locs(end)+1;
                
                end
                    gavegs(iii)=mean(thresh,'omitnan');
                  
                app.Thresh=app.inval*mean(gavegs,'omitnan');
            
            
            end
                
                
            end
            
            
            app.Label_2.Text=num2str(app.Thresh);
            drawnow
            imshow(bwareaopen(imgaussfilt(app.rotatedbone.slice(app.slice).pic,1)>app.Thresh,25),'Parent',app.UIAxes2);
            drawnow
            
            app.GoButton.Enable=1;
        end

        % Button pushed function: NextSampleButton
        function NextSampleButtonPushed(app, event)
            
            dirListing = dir(app.dir);
                for d = 3:length(dirListing)
            name{d-2}=dirListing(d).name;
            
            end
            if app.position<numel(name)
                app.position=app.position+1;
            else
                app.position=1;
            end
            
            
            for iii=app.position


            fN1 = [];
            cname=char(name(iii));   
            subNames= fullfile(app.dir,cname);
            dirsub=dir(subNames);
            fN1= strvcat(fN1,subNames);
            
            app.fN1=fN1;
            
            [n l]=size(fN1);
            
              app.fN2=[];
              fname=[];
              for d = 3:length(dirsub)
                fname{d-2}=dirsub(d).name;
              end 
            for d=1:length(fname)
            app.Names= fullfile(fN1,char(fname(d)));
            app.fN2= strvcat(app.fN2,app.Names);
            end
            
            
            [num len]=size(app.fN2);


            % %% Load in images and place them into structure "ROTATEDBONE"
            app.rotatedbone=[];
            
            for i = 1:num
             b=imread(app.fN2(i,:));
            app.rotatedbone.slice(i).pic=b;
            
            end
            end
            app.SliceSlider.Limits=[1,num]
            drawnow
            
            cla(app.UIAxes);
            cla(app.UIAxes2);
            imshow(app.rotatedbone.slice(1).pic,'Parent',app.UIAxes);
            imshow(bwareaopen(imgaussfilt(app.rotatedbone.slice(app.slice).pic,1)>app.Thresh,25),'Parent',app.UIAxes2);
           
            
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            set(app.UIFigure,'Pointer','watch'); drawnow;
            app.Label_3.Text='Calculating'
            drawnow
            bysample=app.bysample;
            THESH=app.Thresh;
            pixel_noise=app.Pixel_Noise;
             for s_f=1:1


            % Load in directory and folder name
            folder = app.dir
            dirListing = dir(folder);
            
            for d = 3:length(dirListing)
                name{d-2}=dirListing(d).name;
            end
            
            % name=sort_nat(name);
            end
            %% Generate a vector of file names
            
            for iii=1:numel(name)


            fN1 = [];
            cname=char(name(iii));   
            subNames= fullfile(folder,cname);
            dirsub=dir(subNames);
            fN1= strvcat(fN1,subNames);
            
            [n l]=size(fN1);
            
              fN2=[];
              fname=[];
              for d = 3:length(dirsub)
                fname{d-2}=dirsub(d).name;
              end 
            for d=1:length(fname)
            Names= fullfile(fN1,char(fname(d)));
            fN2= strvcat(fN2,Names);
            end
            
            
            [num len]=size(fN2);
             
            
            % %% Load in images and place them into structure "ROTATEDBONE"
            for i = 1:num
             b=imread(fN2(i,:));
            rotatedbone.slice(i).pic=b;
            
            end
            
             
            [R C]=size(rotatedbone.slice(1).pic);
            thick=zeros(length(rotatedbone.slice),C);
            if bysample==1
            for z=1:length(rotatedbone.slice)
                im=rotatedbone.slice(z).pic;
                %creates a histogam of frequencies of all GSV 0-255
                hist=imhist(im);
                %finds the largest GSV 
                locs=find(hist>50);
                %calculates % of max value
                thresh(z)=(locs(end)+1);
                
            end
                   avegs=mean(thresh,'omitnan');
                  
                app.Thresh=app.inval*avegs;
                THRESH=app.Thresh;
            
            end
            for z=1:length(rotatedbone.slice)
                im=rotatedbone.slice(z).pic;
                %applies Gaussian filter sigma=1
                im2=imgaussfilt(im,1);
                
                bw=im2>THESH;


                bw=bwareaopen(bw,pixel_noise);
                h=subplot(1,2,2);
                imshow(bw);
                pause(0.01)
                hold on
                subplot(1,2,1)
                imshow(im)
                
                 %creates bone structure with matric representing slice
                bone(z).vox=bw;
                clear im im2 bw
             
            end
            close all
            [pathstr,nam,ext] = fileparts(fN1);
            bone(1).name=nam;
            savename=[nam '_vox.mat']
            save(savename,'bone')
            
            clearvars -except fN FN FN2 iii fN1 name folder U_lim pixel_noise THESH bysample app
            
            app.Label_3.Text=char([num2str(iii) '/' num2str(numel(name)) 'complete...']);
             
            
            end
            app.Label_3.Text='Complete!'
            drawnow
            set(app.UIFigure,'Pointer','arrow'); drawnow;
        end

        % Value changed function: PixelNoiseEditField
        function PixelNoiseEditFieldValueChanged(app, event)
            app.Pixel_Noise = app.PixelNoiseEditField.Value;
            
        end

        % Button pushed function: FilePathButton_2
        function FilePathButton_2Pushed(app, event)
              % app.dir = uigetdir('C:\Users\');
           app.dir=[];   
           app.dir=uigetdir('C:\Users\yazan\Documents\Research\Thesis');
           app.Label_4.Text=app.dir;
           
        end

        % Button pushed function: GoButton_2
        function GoButton_2Pushed(app, event)
            app.Label_7.Text='Complete!'
            drawnow
            set(app.UIFigure,'Pointer','arrow'); drawnow;
            for s_f=1:1


        % Load in directory and folder name
        folder = app.dir;
        dirListing = dir(folder);
        
        for d = 3:length(dirListing)
            name{d-2}=dirListing(d).name;
        end
        
        % name=sort_nat(name);
        end
        fN = [];
        for d = 1:length(name)
        fileNames = fullfile(folder,char(name(d)));
        fN = strvcat(fN,fileNames);
        end
         
        [num len]=size(fN);
        
        for i=1:num;
            FN=char(fN(i,:));
         load (FN)
        iter=num2str(i);
        [~,var,~]=fileparts(FN);
        
        file(i).name(:)=char(var);
        
        total(i).bone=bone;
        end
        for ii=1:num
           bone=total(ii).bone;
        [R C]=size(bone(1).vox);
        rotatedbone=repmat(struct('vox',zeros(R,C)),numel(bone),1);
        
        for z=2:(numel(bone)-1)
            con=0;
            tot=0;
            
            for x=1:R
                for y=1:C
                    %check next vox for 1 in 3X3X3 array 
                    %exceptions for going out of bounds
                   
                    connect=0;
                          if(bone(z).vox(x,y)==1)
                          
                    xx=x;
                    yy=y;
                    xc=1;
                    yc=1;
                    for q=xx-1:xx+1
                     if((q>0)&&(q<=R))
                         xs(xc)=q;
                         xc=xc+1;
                     end
                    end
                     for p=yy-1:yy+1
                     if((p>0)&&(p<=C))
                         ys(yc)=p;
                         yc=yc+1;
                     end
                    end
                  for zz=z-1:z+1
                      found=find(bone(zz).vox(xs(:),ys(:)));
        
                      if(~isempty(found))
                      connect=connect+numel(found);
                      end
                          
                  end


                          end 
                 rotatedbone(z).vox(x,y)=connect;
                 
                end
            end
            
         
        end 
        rotatedbone(1).name=bone(1).name;
        savename=[bone(1).name '_Link.mat']
        save(savename,'rotatedbone','-v7.3')
        
        clearvars -except fN FN fN1 ii total app num
        app.Label_7.Text=char([num2str(ii) '/' num2str(numel(num)) 'complete...']);
        drawnow
        end
        app.Label_7.Text='Complete!';  
        drawnow
        set(app.UIFigure,'Pointer','arrow'); drawnow;
        end

        % Button pushed function: FilePathButton_3
        function FilePathButton_3Pushed(app, event)
            app.dir=[];   
           app.dir=uigetdir('C:\Users\yazan\Documents\Research\Thesis');
           app.Label_8.Text=app.dir;
        end

        % Button pushed function: GoButton_3
        function GoButton_3Pushed(app, event)
            set(app.UIFigure,'Pointer','watch'); drawnow;
            app.Label_9.Text='Calculating'
            drawnow

            for s_f=1:1

    selec_folder=s_f;
    
    if selec_folder == 2
    clearvars -except selec_folder bonepix zray_length percent
    end
    
% Load in directory and folder name
folder = app.dir;
dirListing = dir(folder);

for d = 3:length(dirListing)
    name{d-2}=dirListing(d).name;
end

% name=sort_nat(name);
end
fN = [];
for d = 1:length(name)
fileNames = fullfile(folder,char(name(d)));
fN = strvcat(fN,fileNames);
end
 
[num len]=size(fN);

Table=["name";"max";"remainder";"percent bridged"];
for i=1:num
    FN=char(fN(i,:));
    load (FN)
count=1;
[Ro Co]=size(rotatedbone(2).vox);
totmask=zeros(Ro,Co);
for z=2:numel(rotatedbone)-1
    
    im=rotatedbone(z).vox;
    
    stat2=regionprops(true(size(im)), imfill(im,'holes'),  'WeightedCentroid');


    if((z<=10)||(z>=numel(rotatedbone)-11))
        
     cx1(count)=stat2.WeightedCentroid(1);
    cy1(count)=stat2.WeightedCentroid(2); 
    count=count+1;
    end
    
end
    cx=mean(cx1,'omitnan');
    cy=mean(cy1,'omitnan');
for z=2:numel(rotatedbone)-1
    distin2=[];
  im=rotatedbone(z).vox;
  [Ro Co]=size(im);

  count=1;


    [bx by]=find(rotatedbone(z).vox);
       allDistances = sqrt((bx-cx).^2 + (by-cy).^2);
       allAngles = round(atan2d((bx-cx),(by-cy))); 
      if(~isnan(allAngles))
uangles=unique(allAngles);


  for w=1:numel(uangles)
      angle=uangles(w);
      ind=find(allAngles==angle);
    [mx mxi]=max(allDistances(ind));

      distin2(w)=ind(mxi);


      De(w)=mx;
      x1=bx(ind(mxi));
      y1=by(ind(mxi));
      
      
      XY(w,1)=x1+(mx*.3*(x1-cx)/mx);
      XY(w,2)=y1+(mx*.3*(y1-cy)/mx); 
           
      
  end  


       ang = atan2( (XY(:,2)-cy),(XY(:,1)-cx));
    [sortedAngles, sortIndices] = sort(ang);
    xnew=XY(:,1);
    ynew=XY(:,2);
     rx = xnew(sortIndices);
    ry = ynew(sortIndices);


    mask1=poly2mask(ry,rx,Ro,Co);
      
     
    mask=imfill(mask1,'holes');
    leftover=rotatedbone(z).vox.*~mask;
    r=[];
    c=[];
    [r,c]=find(leftover);
    if(~isempty(r))
    for i=1:numel(r)
        mask(r(i),c(i))=1;
    end
    end
    
    
    totmask=totmask+mask;
      end  
     
end
for z=2:numel(rotatedbone)
    objact(:,:,z)=rotatedbone(z).vox;
    objmask(:,:,z)=totmask;


end
      for xx=1:Ro
          for yy=1:Co


             zfind=find(objact(xx,yy,:));
              zfind2=find(objmask(xx,yy,:));
              zrayact(xx,yy)=sum(objact(xx,yy,zfind));
              
              zrayest(xx,yy)=numel(objmask(xx,yy,zfind2));
              
              if(zrayest(xx,yy)==0)
                zraynorm(xx,yy)=0;  
              else
              zraynorm(xx,yy)=zrayact(xx,yy)/zrayest(xx,yy);
              end
          end
      end
    [r1,c1,v1]=find(zrayest);
    [r2,c2,v2]=find(zrayact);
    [r3,c3,v3]=find(zraynorm);


        [r,c,v]=find(zraynorm);
     allDistances2 = sqrt((c-cx).^2 + (r-cy).^2);
       allAngles2 = round(atan2d((c-cx),(r-cy)));


        if(~isnan(allAngles2))
uangles2=unique(allAngles2);

  for w=1:numel(uangles2)
      angle=uangles2(w);
      ind=find(allAngles2==angle);

[mx mxi]=max(v(ind));
  
      
      xmaxconn(w)=c(ind(mxi));
      ymaxconn(w)=r(ind(mxi));

      
      maxconndis(w)=allDistances2(ind(mxi));
  
      XY2(w,1)=xmaxconn(w);
      XY2(w,2)=ymaxconn(w); 
      
  end  
    
        end 

       centroid=mean(XY2);
    D1=[(XY2(:,1)-centroid(1)).^2, (XY2(:,1)-centroid(1)).*(XY2(:,2)-centroid(2)),...
      (XY2(:,2)-centroid(2)).^2];
  D2 = [XY2(:,1)-centroid(1), XY2(:,2)-centroid(2), ones(size(XY2,1),1)];
    S1 = D1'*D1;
    S2 = D1'*D2;
    S3 = D2'*D2;
    T = -inv(S3)*S2';
    M = S1 + S2*T;
    M = [M(3,:)./2; -M(2,:); M(1,:)./2];
    [evec,eval] = eig(M);
    cond = 4*evec(1,:).*evec(3,:)-evec(2,:).^2;
    A1 = evec(:,find(cond>0));
    A = [A1; T*A1];
    A4 = A(4)-2*A(1)*centroid(1)-A(2)*centroid(2);
    A5 = A(5)-2*A(3)*centroid(2)-A(2)*centroid(1);
    A6 = A(6)+A(1)*centroid(1)^2+A(3)*centroid(2)^2+...
         A(2)*centroid(1)*centroid(2)-A(4)*centroid(1)-A(5)*centroid(2);
    A(4) = A4;  A(5) = A5;  A(6) = A6;
    A = A/norm(A);
    syms xx  yy
    B=A(2);
    C=A(3);
    D=A(4);
    E=A(5);
    F=A(6);
    A=A(1);
     e = 4*A*C-B^2; if e<=0, error('This conic is not an ellipse.'), end
   x0 = (B*E-2*C*D)/e; y0 = (B*D-2*A*E)/e;   % Ellipse center
   F0 = -2*(A*x0^2+B*x0*y0+C*y0^2+D*x0+E*y0+F);
   g = sqrt((A-C)^2+B^2); a = F0/(A+C+g); b = F0/(A+C-g);
   if (a<=0)|(b<=0), error('This is a degenerate ellipse.'), end
   a = sqrt(a);  b = sqrt(b); % Major & minor axes
   t = 1/2*atan2(B,A-C); ct = cos(t); st = sin(t);   % Rotation angle
   p = linspace(0,2*pi,100); cp = cos(p); sp = sin(p);   % Variable parameter
   xx = x0+a*ct*cp-b*st*sp; yy = y0+a*st*cp+b*ct*sp;   % Generate points on ellipse   


    mask1=poly2mask(xx,yy,Ro,Co);
    
    maskout=imfill(mask1,'holes'); 
    maskin=~maskout;
   
    for zzz=2:numel(rotatedbone)-1   
        objinner(:,:,zzz)=rotatedbone(zzz).vox.*maskout;
        objcallus(:,:,zzz)=rotatedbone(zzz).vox.*maskin;
        objTotal(:,:,zzz)=rotatedbone(zzz).vox;
    end   
     for xx=1:Ro
          for yy=1:Co


            zfind2=find(objcallus(xx,yy,:));
              zrayact2(xx,yy)=sum(objcallus(xx,yy,zfind2));


              if(zrayest(xx,yy)==0)
                zraynorm2(xx,yy)=0;  
              else
              zraynorm2(xx,yy)=zrayact2(xx,yy)/zrayest(xx,yy);
              end
          end
      end
    [r1,c1,v1]=find(zrayest);
    [r2,c2,v2]=find(zrayact);
    [r3,c3,v3]=find(zraynorm);


        [r2,c2,v2]=find(zraynorm2);
     allDistances3 = sqrt((c2-cx).^2 + (r2-cy).^2);
       allAngles3 = round(atan2d((c2-cx),(r2-cy)));


        if(~isnan(allAngles3))
uangles3=unique(allAngles3);

  for w=1:numel(uangles3)
      angle=uangles3(w);
      ind=find(allAngles3==angle);

[mx mxi]=max(v(ind));
[md mdi]=max(allDistances3(ind));

%smaller inner
      XY3(w,1)=r2(mdi)-(mx*.5*(r2(mdi)-cx)/mx);
      XY3(w,2)=c2(mdi)-(mx*.5*(c2(mdi)-cy)/mx); 


      xmaxconn2(w)=c2(ind(mxi));
      ymaxconn2(w)=r2(ind(mxi));

      
      maxconndis2(w)=allDistances3(ind(mxi));


  end  
    
        end 
     centroid=mean(XY3);
    D1=[(XY3(:,1)-centroid(1)).^2, (XY3(:,1)-centroid(1)).*(XY3(:,2)-centroid(2)),...
      (XY3(:,2)-centroid(2)).^2];
  D2 = [XY3(:,1)-centroid(1), XY3(:,2)-centroid(2), ones(size(XY3,1),1)];
    S1 = D1'*D1;
    S2 = D1'*D2;
    S3 = D2'*D2;
    T = -inv(S3)*S2';
    M = S1 + S2*T;
    M = [M(3,:)./2; -M(2,:); M(1,:)./2];
    [evec,eval] = eig(M);
    cond = 4*evec(1,:).*evec(3,:)-evec(2,:).^2;
    A1 = evec(:,find(cond>0));
    A = [A1; T*A1];
    A4 = A(4)-2*A(1)*centroid(1)-A(2)*centroid(2);
    A5 = A(5)-2*A(3)*centroid(2)-A(2)*centroid(1);
    A6 = A(6)+A(1)*centroid(1)^2+A(3)*centroid(2)^2+...
         A(2)*centroid(1)*centroid(2)-A(4)*centroid(1)-A(5)*centroid(2);
    A(4) = A4;  A(5) = A5;  A(6) = A6;
    A = A/norm(A);
    syms xx  yy
    B=A(2);
    C=A(3);
    D=A(4);
    E=A(5);
    F=A(6);
    A=A(1);
     e = 4*A*C-B^2; if e<=0, error('This conic is not an ellipse.'), end
   x0 = (B*E-2*C*D)/e; y0 = (B*D-2*A*E)/e;   % Ellipse center
   F0 = -2*(A*x0^2+B*x0*y0+C*y0^2+D*x0+E*y0+F);
   g = sqrt((A-C)^2+B^2); a = F0/(A+C+g); b = F0/(A+C-g);
   if (a<=0)|(b<=0), error('This is a degenerate ellipse.'), end
   a = sqrt(a);  b = sqrt(b); % Major & minor axes
    
    
    objcallusbw=objcallus>=1;
    objinnerbw=objinner>=1;
    objTotalbw=objTotal>=1;
    statscal=regionprops3(objcallusbw,'Volume','Image','Centroid','Orientation','VoxelList');
    statsinner=regionprops3(objinnerbw,'Volume','Image','Centroid','Orientation','VoxelList');
    statstotal=regionprops3(objTotalbw,'Volume','Image','Centroid','Orientation','VoxelList');


str=strsplit(rotatedbone(1).name,'_');
str=string(str(1));
BonePart(1).name=str;
BonePart(1).TV=pi*a*b*numel(rotatedbone);
BonePart(1).zrayact=zrayact;
BonePart(1).zrayest=zrayest;
BonePart(1).zraynorm=zraynorm;
BonePart(1).zraynorm2=zraynorm2;
BonePart(1).maxconndist=maxconndis;
BonePart(1).xmaxconn=xmaxconn;
BonePart(1).ymaxconn=ymaxconn;
BonePart(1).maxconndistcal=maxconndis2;
BonePart(1).xmaxconncal=xmaxconn2;
BonePart(1).ymaxconncal=ymaxconn2;
BonePart(1).Callus.obj=objcallusbw;
BonePart(1).Callus.objconn=objcallus;
BonePart(1).Callus.stat=statscal;
BonePart(1).Inner.obj=objinnerbw;
BonePart(1).Inner.objconn=objinner;
BonePart(1).Inner.stat=statsinner;
BonePart(1).Centroid=[cx,cy];
BonePart(1).Total.obj=objTotalbw;
BonePart(1).Total.objconn=objTotal;
BonePart(1).Total.stat=statstotal;

    Calvol=BonePart(1).Callus.stat;
    calmax=max(Calvol.Volume);
    indcal=find(Calvol.Volume>=.15*calmax);
    
    calsum=sum(Calvol.Volume(indcal(:)));
    
    Innervol=BonePart(1).Inner.stat;
    Inmax=max(Innervol.Volume);
    indin=find(Innervol.Volume>=.15*Inmax);
    tv=BonePart(1).TV;
    Insum=sum(Innervol.Volume(indin(:)));
   if(Inmax/Insum>=.95)
       BT="Union";
   else
       BT="Butterfly";
   end
   
    
       siz=size(BonePart(1).Inner.obj);
       obj=zeros(siz);
       obj(:,:,1:5)=1;
       obj(:,:,end-5:end)=1;

       objintemp=zeros(siz);

countin=0;
countout=0;
       for iii=1:numel(indin)
           objtemp=obj;
        vox=(Innervol.VoxelList{indin(iii),1});
       inds=sub2ind(siz,vox(:,2),vox(:,1),vox(:,3));
       objtemp(inds)=1;
       objintemp(inds)=1;
       objtemp=objtemp>=1;
       objintemp=objintemp>=1;
       
       
       statobj=regionprops3(objtemp,'Volume','VoxelList');
       
       numo=3;
       numt=numel(statobj.Volume);
       if(numo-numt>=2)
          countin=countin+1;
          effin(countin)=iii;
           
           
       end
       end
       statobj=regionprops3(objintemp,'Volume','VoxelList');
      
       
       if(countin==0)
           BT="Non-Union";
        numo=numel(statobj.Volume);
        for ii=1:numel(Calvol.VoxelList)
                
           objtemp=objintemp;
            
            vox2=Calvol.VoxelList{ii,1};
             inds2=sub2ind(siz,vox2(:,2),vox2(:,1),vox2(:,3));
            objtemp(inds2)=1;
            objtemp=objtemp>=1;
            
            stattemp=regionprops3(objtemp,'Volume');
            numt=numel(stattemp.Volume);
            
            if(numo-numt>=2)
                countout=countount+1;
                effout(countout)=ii;
            end
        end
        
       else
           numo=3;
            for ii=1:numel(Calvol.VoxelList)
                
           objtemp=obj;
            
            vox2=Calvol.VoxelList{ii,1};
             inds2=sub2ind(siz,vox2(:,2),vox2(:,1),vox2(:,3));
            objtemp(inds2)=1;
            objtemp=objtemp>=1;
            
            stattemp=regionprops3(objtemp,'Volume');
            numt=numel(stattemp.Volume);
            
            if(numo-numt>=2)
                countout=countout+1;
                effout(countout)=ii;
            end
        
        
       end
        
       end
       effinobj=zeros(siz);
       effoutobj=zeros(siz);
       effobj=zeros(siz);
       
       if(countin>=1)
        for xx=1:countin
            effvox=Innervol.VoxelList{effin(xx),1};
            effinds=sub2ind(siz,effvox(:,2),effvox(:,1),effvox(:,3));
         effinobj(effinds)=1;
         effobj(effinds)=1;
         effinobj=effinobj>=1;
         effobj=effobj>=1;
        end
       end
       if(countout>=1)
           for yy=1:countout
               effvox=Calvol.VoxelList{effout(yy),1};
               effinds=sub2ind(siz,effvox(:,2),effvox(:,1),effvox(:,3));
            effoutobj(effinds)=1;
            effobj(effinds)=1;
            effoutobj=effoutobj>=1;
            effobj=effobj>=1;
           end
       end
    statseffcal=regionprops3(effoutobj,'Volume','Image','Centroid','Orientation','VoxelList');
    statseffinner=regionprops3(effinobj,'Volume','Image','Centroid','Orientation','VoxelList');
    statseff=regionprops3(effobj,'Volume','Image','Centroid','Orientation','VoxelList');


    BonePart(1).effTotal.obj=effobj;
    BonePart(1).effTotal.stat=statseff;
    BonePart(1).effTotal.objconn=objTotal.*effobj;
    
    BonePart(1).effInner.obj=effinobj;
    BonePart(1).effInner.objconn=effinobj.*objinner;
    BonePart(1).effInner.stat=statseffinner;
    
    BonePart(1).effCallus.obj=effoutobj;
    BonePart(1).effCallus.objconn=effoutobj.*objcallus;
    BonePart(1).effCallus.stat=statseffcal;
    
    BonePart(1).BreakType=BT;   
       
       
savename=strcat(str, '_BonePart')

save(savename,'BonePart','-v7.3')

clearvars -except fn name folder Table fN num i
 app.Label_9.Text=char([num2str(i) '/' num2str(numel(num)) 'complete...']);
 drawnow
end
app.Label_9.Text='Complete!';
drawnow
set(app.UIFigure,'Pointer','arrow'); drawnow;
        end

        % Button pushed function: FilePathButton_4
        function FilePathButton_4Pushed(app, event)
              app.dir=[];   
           app.dir=uigetdir('C:\Users\yazan\Documents\Research\Thesis');
           app.Label_10.Text=app.dir;
        end

        % Button pushed function: GoButton_4
        function GoButton_4Pushed(app, event)
            set(app.UIFigure,'Pointer','watch'); drawnow;
            app.Label_11.Text='Calculating'
            drawnow
    
            
            for s_f=1:1

    
% Load in directory and folder name
folder = app.dir;
dirListing = dir(folder);

for d = 3:length(dirListing)
    name{d-2}=dirListing(d).name;
end

% name=sort_nat(name);
end
fN = [];
for d = 1:length(name)
fileNames = fullfile(folder,char(name(d)));
fN = strvcat(fN,fileNames);
end
 
[num len]=size(fN);

for i=1:num;
 FN=char(fN(i,:));
 load (FN)


Bone(1).name=BonePart(1).name;

objCallus=BonePart(1).Callus.objconn;
objInner=BonePart(1).Inner.objconn;
objTotal=BonePart(1).Total.objconn;
objeffIn=BonePart(1).effInner.objconn;
objeffCal=BonePart(1).effCallus.objconn;
objeffTotal=BonePart(1).effTotal.objconn;


[Ex,Wy,Ze]=size(objCallus);
Object(1).obj=zeros(Ex,Wy,Ze);
Object(2).obj=zeros(Ex,Wy,Ze);
Object(3).obj=zeros(Ex,Wy,Ze);
Object(4).obj=zeros(Ex,Wy,Ze);
Object(5).obj=zeros(Ex,Wy,Ze);
Object(6).obj=zeros(Ex,Wy,Ze);

Object(1).obj=objCallus;
Object(2).obj=objInner;
Object(3).obj=objTotal;
Object(4).obj=objeffCal;
Object(5).obj=objeffIn;
Object(6).obj=objeffTotal;


for I=1:numel(Object)

clearvars -except fn name folder fN table Object Bone Ex Wy Ze I z BonePart i num app
for z=1:Ze
   im=zeros(Ex,Wy);
   im=Object(I).obj(:,:,z);
   bound=[];
   bound=bwboundaries(im);
   sslice=cellfun('length',bound);


   stats = regionprops('table',im,'Centroid',...
    'MajorAxisLength','MinorAxisLength','Area');
    centers = stats.Centroid;

bound=bwboundaries(im);

    if(isempty(centers))

     
     tv=[];

    else
count=1;
 bound2=[];
 bound2=bwboundaries(bwareaopen(im,25)); 
       for k=1:numel(bound2)
    boundaryx2= bound2{k}(:,2);
    boundaryy2= bound2{k}(:,1);
        
        for q=1:numel(boundaryx2)
            bx(count)=boundaryx2(q);
            by(count)=boundaryy2(q);
                count=count+1;
        end
       end
      cx=[];
      cy=[];
  cx=BonePart(1).Centroid(1);
  cy=BonePart(1).Centroid(2);
  %calculate all distaces and associated angles
  allDistances=[];
  allAngles=[];
  
       allDistances = sqrt((bx-cx).^2 + (by-cy).^2);
       allAngles = round(atan2d((bx-cx),(by-cy))); 
        
       if(~isnan(allAngles))
           uangles=[];
uangles=unique(allAngles);
%find largest distance for every angle
distin2=[];
  for w=1:numel(uangles)
      angle=uangles(w);
      ind=find(allAngles==angle);
      [mxl mxi]=max(allDistances(ind));
      distin2(w)=find(allDistances==mxl,1);
      [ml mi]=min(allDistances(ind));
      distin(w)=find(allDistances==ml,1);
  end
       end
XY=[];
  for e=1:numel(distin2)
      outerx(e)=bx(distin2(e));
      outery(e)=by(distin2(e));
      XY(e,1)=bx(distin2(e));
      XY(e,2)=by(distin2(e));
  end
  
  %get canal dimensions
  for e=1:numel(distin)
      XY2(e,1)=bx(distin(e));
      XY2(e,2)=by(distin(e));
  end
clear D1 D2 S1 S2 S3 T M A1 A A4 A5 A5 A6 B C D E F X0 F0 g x y app
  
%%code based off ellipse fit (2009, Nikolai Chernov)
%ellipse approximated to determine TV for the slice
      centroid=mean(XY);
    D1=[(XY(:,1)-centroid(1)).^2, (XY(:,1)-centroid(1)).*(XY(:,2)-centroid(2)),...
      (XY(:,2)-centroid(2)).^2];
  D2 = [XY(:,1)-centroid(1), XY(:,2)-centroid(2), ones(size(XY,1),1)];
    S1 = D1'*D1;
    S2 = D1'*D2;
    S3 = D2'*D2;
    T = -inv(S3)*S2';
    M = S1 + S2*T;
    M = [M(3,:)./2; -M(2,:); M(1,:)./2];
    [evec,eval] = eig(M);
    cond = 4*evec(1,:).*evec(3,:)-evec(2,:).^2;
    A1 = evec(:,find(cond>0));
    A = [A1; T*A1];
    A4 = A(4)-2*A(1)*centroid(1)-A(2)*centroid(2);
    A5 = A(5)-2*A(3)*centroid(2)-A(2)*centroid(1);
    A6 = A(6)+A(1)*centroid(1)^2+A(3)*centroid(2)^2+...
         A(2)*centroid(1)*centroid(2)-A(4)*centroid(1)-A(5)*centroid(2);
    A(4) = A4;  A(5) = A5;  A(6) = A6;
    A = A/norm(A);
    syms x  y
    B=A(2);
    C=A(3);
    D=A(4);
    E=A(5);
    F=A(6);
    A=A(1);
     e = 4*A*C-B^2; if e<=0, error('This conic is not an ellipse.'), end
   x0 = (B*E-2*C*D)/e; y0 = (B*D-2*A*E)/e;   % Ellipse center
   F0 = -2*(A*x0^2+B*x0*y0+C*y0^2+D*x0+E*y0+F);
   g = sqrt((A-C)^2+B^2); a = F0/(A+C+g); b = F0/(A+C-g);
   if (a<=0)|(b<=0), error('This is a degenerate ellipse.'), end
   a = sqrt(a);  b = sqrt(b); % Major & minor axes
   t = 1/2*atan2(B,A-C); ct = cos(t); st = sin(t);   % Rotation angle
   p = linspace(0,2*pi,100); cp = cos(p); sp = sin(p);   % Variable parameter
   x = x0+a*ct*cp-b*st*sp; y = y0+a*st*cp+b*ct*sp;   % Generate points on ellipse
 

%Get TV 
    Bone(I).TV(z)=pi*a*b;
    tv(z)=pi*a*b;
    
  clear D1 D2 S1 S2 S3 T M A1 A A4 A5 A5 A6 B C D E F X0 F0 g x y app
  
%%code based off ellipse fit (2009, Nikolai Chernov)
%ellipse approximated to determine TV for the slice
      centroid=mean(XY2);
    D1=[(XY2(:,1)-centroid(1)).^2, (XY2(:,1)-centroid(1)).*(XY2(:,2)-centroid(2)),...
      (XY2(:,2)-centroid(2)).^2];
  D2 = [XY2(:,1)-centroid(1), XY2(:,2)-centroid(2), ones(size(XY2,1),1)];
    S1 = D1'*D1;
    S2 = D1'*D2;
    S3 = D2'*D2;
    T = -inv(S3)*S2';
    M = S1 + S2*T;
    M = [M(3,:)./2; -M(2,:); M(1,:)./2];
    [evec,eval] = eig(M);
    cond = 4*evec(1,:).*evec(3,:)-evec(2,:).^2;
    A1 = evec(:,find(cond>0));
    A = [A1; T*A1];
    A4 = A(4)-2*A(1)*centroid(1)-A(2)*centroid(2);
    A5 = A(5)-2*A(3)*centroid(2)-A(2)*centroid(1);
    A6 = A(6)+A(1)*centroid(1)^2+A(3)*centroid(2)^2+...
         A(2)*centroid(1)*centroid(2)-A(4)*centroid(1)-A(5)*centroid(2);
    A(4) = A4;  A(5) = A5;  A(6) = A6;
    A = A/norm(A);
    syms x  y
    B=A(2);
    C=A(3);
    D=A(4);
    E=A(5);
    F=A(6);
    A=A(1);
     e = 4*A*C-B^2; if e<=0, error('This conic is not an ellipse.'), end
   x0 = (B*E-2*C*D)/e; y0 = (B*D-2*A*E)/e;   % Ellipse center
   F0 = -2*(A*x0^2+B*x0*y0+C*y0^2+D*x0+E*y0+F);
   g = sqrt((A-C)^2+B^2); a = F0/(A+C+g); b = F0/(A+C-g);
   if (a<=0)|(b<=0), error('This is a degenerate ellipse.'), end
   a2 = sqrt(a);  b2 = sqrt(b); % Major & minor axes
   t = 1/2*atan2(B,A-C); ct = cos(t); st = sin(t);   % Rotation angle
   p = linspace(0,2*pi,100); cp = cos(p); sp = sin(p);   % Variable parameter
   x = x0+a*ct*cp-b*st*sp; y = y0+a*st*cp+b*ct*sp;   % Generate points on ellipse

%Get canal out
    Bone(I).TVcanal(z)=(pi*a2*b2);
    tvcanal(z)=(pi*a2*b2);


    %get histogram each bin 1-27
    for g=1:28
        bin=0;
        rcount=0;
        rcount=sum(im(:,:)==g);
        bin=sum(rcount);
        hist(g)=bin;
    end
    %get summation of histogram for every slize
    for h=1:28
        
       sumhist(z,h)=sum(hist(h:end)); 
        sumhist2(z,h)=sum(hist(1:h));
    end
    [~,ii]=max(hist);
    
  if I==1
Bone(z).peak1=ii;
Bone(z).hist1=hist; 
  elseif I==2
 Bone(z).peak2=ii;
Bone(z).hist2=hist;     
  elseif I==3
 Bone(z).peak3=ii;
Bone(z).hist3=hist;     
  elseif I==4
Bone(z).peak4=ii;
Bone(z).hist4=hist;      
  elseif I==5
 Bone(z).peak5=ii;
Bone(z).hist5=hist;     
  elseif I==6
 Bone(z).peak6=ii;
Bone(z).hist6=hist;                 
  end
end
end
Bone(1).ROI="Callus";
Bone(2).ROI="Inner";
Bone(3).ROI="Total";
Bone(4).ROI="Effective Callus";
Bone(5).ROI="Effective Inner";
Bone(6).ROI="Effective Total";
 %get max min and ave for every possible linkage value
 for ii=1:27
     holdsumhist=[];
     holdsumhist2=[];
     
     holdsumhist=sumhist(1:end,ii);
     holdsumhist2=sumhist2(1:end,ii);


    maxhist(ii)=max(holdsumhist);
    minhist(ii)=min(holdsumhist(holdsumhist>0));
    avehist(ii)=mean(holdsumhist,'omitnan');
    
    maxhist2(ii)=max(holdsumhist2);
    minhist2(ii)=min(holdsumhist2(holdsumhist>0));
    avehist2(ii)=mean(holdsumhist2,'omitnan');


 end
tv=Bone(I).TV;
Bone(I).TVmax=max(tv);
Bone(I).TVmin=min(tv(tv>0));
Bone(I).TVave=mean(tv,'omitnan');
Bone(I).histmax=maxhist;
Bone(I).histmin=minhist;
Bone(I).avehist=avehist;
Bone(I).histmax2=maxhist2;
Bone(I).histmin2=minhist2;
Bone(I).avehist2=avehist2;


end
BVCallus=0;
BVCortical=0;
BVTotal=0;
TVCallus=0;
TVCortical=0;
TVTotal=0;

[r c] = size(Bone);
for b=2:c-1
    if(~isempty(Bone(b).hist1))
    BVCallus=BVCallus+sum(Bone(b).hist1(1,:));
    end
    if(~isempty(Bone(b).hist2))
    BVCortical=BVCortical+sum(Bone(b).hist2(1,:));
    end
    if(~isempty(Bone(b).hist3))
    BVTotal=BVTotal+sum(Bone(b).hist3(1,:));
    end
end


TVTotal=sum(Bone(3).TV(1,:));
TVCortical=sum(Bone(2).TV(1,:));
TVCanal=sum(Bone(2).TVcanal(1,:));

TVCallus=TVTotal-TVCortical;


Bone(1).TVTotal=TVTotal;
Bone(1).TVCortical=TVCortical;
Bone(1).TVCallus=TVCallus;

Bone(1).BVTotal=BVTotal;
Bone(1).BVCortical=BVCortical;
Bone(1).BVCallus=BVCallus;

Bone(1).BVTVTot=BVTotal/TVTotal;
Bone(1).BVTVCort=BVCortical/TVCortical;
Bone(1).BVTVCal=BVCallus/TVCallus;

table=["name","BV Callus", "BV Cortical", "BV Total", "TVCallus", "TV Cortical", "TV Total", "BV/TV Callus", "BV/TV Cortical", "BV/ TV Total"];
col=[Bone(1).name, string(BVCallus), string(BVCortical), string(BVTotal), string(TVCallus), string(TVCortical), string(TVTotal),  string(BVCallus/TVCallus), string(BVCortical/TVCortical),string(BVTotal/TVTotal)];
table=[table; col];

savename=strcat(Bone(1).name,'_ConnHist.mat')
save(savename,'Bone')

clearvars -except fn name folder fN table num i app
       
 app.Label_11.Text=char([num2str(i) '/' num2str(numel(num)) 'complete...']);
 drawnow              

end   
 app.Label_11.Text='Complete';
 drawnow
set(app.UIFigure,'Pointer','arrow'); drawnow;
        end

        % Button pushed function: FilePathButton_5
        function FilePathButton_5Pushed(app, event)
                  app.dir=[];   
           app.dir=uigetdir('C:\Users\yazan\Documents\Research\Thesis');
           app.Label_6.Text=app.dir;
           drawnow
           app.GoButton_5.Enable=1;
        end

        % Value changed function: cutoffSlider
        function cutoffSliderValueChanged(app, event)
            app.bound = round(app.cutoffSlider.Value);
            app.Label_12.Text=num2str(app.bound);
            drawnow
        end

        % Callback function
        function UBoundSliderValueChanged(app, event)
            
            
        end

        % Button pushed function: GoButton_5
        function GoButton_5Pushed(app, event)
            set(app.UIFigure,'Pointer','watch'); drawnow;
            app.Label_5.Text='Calculating'
            drawnow

            if app.ForwardButton.Value==1
                forward=1;
            end
            if app.BackwardButton.Value==1
                forward=0;
            end
            
            for s_f=1:1
            
              bound=app.bound;  
                
            % Load in directory and folder name
            folder = app.dir;
            dirListing = dir(folder);
            
            for d = 3:length(dirListing)
                name{d-2}=dirListing(d).name;
            end
            
            % name=sort_nat(name);
            end
            fN = [];
            for d = 1:length(name)
            fileNames = fullfile(folder,char(name(d)));
            fN = strvcat(fN,fileNames);
            end
             
            [num len]=size(fN);
            Total=[];
       for i=1:num


        FN=char(fN((i),:));
         load (FN)


         for h=1:28


        for ii=6
            if((isempty(Bone(ii).TVmin)))
                Bone(ii).TVmin=nan;
            end
            if ((isempty(Bone(ii).TVave)))
                Bone(ii).TVave=nan;
            end
             if ((isempty(Bone(ii).TVmax)))
                 Bone(ii).TVmax=nan;
             end
             for z=2:numel(Bone)-1
            
           q(z)=sum(Bone(z).hist1(:)); 
            
        end
        end
         end
         
        
        %Total=[row];
        
         if forward ==1
          nrow=[Bone(1).name, string(Bone(1).histmax(bound)), string(Bone(1).histmin(bound)), string(Bone(1).avehist(bound)), string(Bone(2).histmax(bound)), string(Bone(2).histmin(bound)), string(Bone(2).avehist(bound)), string(Bone(3).histmax(bound)), string(Bone(3).histmin(bound)), string(Bone(3).avehist(bound)), string(Bone(4).histmax(bound)), string(Bone(4).histmin(bound)), string(Bone(4).avehist(bound)), string(Bone(5).histmax(bound)), string(Bone(5).histmin(bound)), string(Bone(5).avehist(bound)), string(Bone(6).histmax(bound)), string(Bone(6).histmin(bound)), string(Bone(6).avehist(bound)), string(Bone(1).TVmax), string(Bone(1).TVmin), string(Bone(1).TVave)];    
         end
        if forward==0
             
        nrow=[Bone(1).name, string(Bone(1).histmax2(bound)), string(Bone(1).histmin2(bound)), string(Bone(1).avehist2(bound)), string(Bone(2).histmax2(bound)), string(Bone(2).histmin2(bound)), string(Bone(2).avehist2(bound)), string(Bone(3).histmax2(bound)), string(Bone(3).histmin2(bound)), string(Bone(3).avehist2(bound)), string(Bone(4).histmax2(bound)), string(Bone(4).histmin2(bound)), string(Bone(4).avehist2(bound)), string(Bone(5).histmax2(bound)), string(Bone(5).histmin2(bound)), string(Bone(5).avehist2(bound)), string(Bone(6).histmax2(bound)), string(Bone(6).histmin2(bound)), string(Bone(6).avehist2(bound)), string(Bone(1).TVmax), string(Bone(1).TVmin), string(Bone(1).TVave)];
        end
        
       
         Total=[Total; nrow];
         
        app.Label_5.Text=char([num2str(i) '/' num2str(numel(num)) ' complete...']);
        drawnow
       end
     
     
          app.UITable.ColumnName=["Name", "Callus LV max", "Callus LV min", "Callus LV ave", "Cortical LV max", "Cortical LV min", "Cortical LV ave", "Total LV Max", "Total LV min", "Total LV ave", "Callus LV max", "Callus LV min", "Callus LV ave", "EffCortical LV max", "EffCortical LV min", "EffCortical LV ave", "EffTotal LV Max", "EffTotal LV min", "EffTotal LV ave", "TV max", "TV min", "TV ave"];
            app.UITable.Data=Total;
           app.Label_5.Text='Complete!';
       drawnow
       set(app.UIFigure,'Pointer','arrow'); drawnow;


        end

        % Button pushed function: FilePathButton_6
        function FilePathButton_6Pushed(app, event)
            app.dir=[];   
           app.dir=uigetdir('C:\Users\yazan\Documents\Research\Thesis');
           app.Label_13.Text=app.dir;
           drawnow
           app.GoButton_6.Enable=1;
        end

        % Button pushed function: GoButton_6
        function GoButton_6Pushed(app, event)
        set(app.UIFigure,'Pointer','watch'); drawnow;    
            
            for s_f=1:1


% Load in directory and folder name
folder = app.dir;
dirListing = dir(folder);

for d = 3:length(dirListing)
    name{d-2}=dirListing(d).name;
end

% name=sort_nat(name);
end
fN = [];
for d = 1:length(name)
fileNames = fullfile(folder,char(name(d)));
fN = strvcat(fN,fileNames);
end
 
[num len]=size(fN);

Total=[];
                    
for i=1:num
    FN=char(fN(i,:));
    load (FN)
 
    name=BonePart(1).name;
    bt=BonePart(1).BreakType;
    

    cx=round(BonePart(1).Centroid(1));
    offsetx=0;
    cy=round(BonePart(1).Centroid(2));
    offsety=0;
    os2=25;
    % creates 4 objects representing the 4 corticies of bone. This is done
    % for the Inner object, Inner object>26, Callus object and Callus
    % object >26
    Bridge(1).Inner(1).obj=BonePart(1).Inner.obj(cy-os2:cy+os2,1:cx-offsetx,5:end-5);
    Bridge(1).Inner(1).stat=regionprops3(Bridge(1).Inner(1).obj,'Volume','VoxelList');
    Bridge(1).Inner(1).size=size(Bridge(1).Inner(1).obj);
    Bridge(1).Inner(2).obj=BonePart(1).Inner.obj(cy-os2:cy+os2,cx+offsetx:end,5:end-5);
    Bridge(1).Inner(2).stat=regionprops3(Bridge(1).Inner(2).obj,'Volume','VoxelList');
    Bridge(1).Inner(2).size=size(Bridge(1).Inner(2).obj);
    Bridge(1).Inner(3).obj=BonePart(1).Inner.obj(1:cy-offsety,cx-os2:cx+os2,5:end-5);
    Bridge(1).Inner(3).stat=regionprops3(Bridge(1).Inner(3).obj,'Volume','VoxelList');
    Bridge(1).Inner(3).size=size(Bridge(1).Inner(3).obj);
    Bridge(1).Inner(4).obj=BonePart(1).Inner.obj(cy+offsety:end,cx-os2:cx+os2,5:end-5);
    Bridge(1).Inner(4).stat=regionprops3(Bridge(1).Inner(4).obj,'Volume','VoxelList');
    Bridge(1).Inner(4).size=size(Bridge(1).Inner(4).obj);


    Bridge(1).Inner22(1).obj=BonePart(1).Inner.objconn(cy-os2:cy+os2,1:cx-offsetx,5:end-5)>=26;
    Bridge(1).Inner22(1).stat=regionprops3(Bridge(1).Inner22(1).obj,'Volume','VoxelList');
    Bridge(1).Inner22(1).size=size(Bridge(1).Inner22(1).obj);
    Bridge(1).Inner22(2).obj=BonePart(1).Inner.objconn(cy-os2:cy+os2,cx+offsetx:end,5:end-5)>=26;
    Bridge(1).Inner22(2).stat=regionprops3(Bridge(1).Inner22(2).obj,'Volume','VoxelList');
    Bridge(1).Inner22(2).size=size(Bridge(1).Inner22(2).obj);
    Bridge(1).Inner22(3).obj=BonePart(1).Inner.objconn(1:cy-offsety,cx-os2:cx+os2,5:end-5)>=26;
    Bridge(1).Inner22(3).stat=regionprops3(Bridge(1).Inner22(3).obj,'Volume','VoxelList');
    Bridge(1).Inner22(3).size=size(Bridge(1).Inner22(3).obj);
    Bridge(1).Inner22(4).obj=BonePart(1).Inner.objconn(cy+offsety:end,cx-os2:cx+os2,5:end-5)>=26;
    Bridge(1).Inner22(4).stat=regionprops3(Bridge(1).Inner22(4).obj,'Volume','VoxelList');
    Bridge(1).Inner22(4).size=size(Bridge(1).Inner22(4).obj);
    
    
    Bridge(1).Callus(1).obj=BonePart(1).Callus.obj(cy-os2:cy+os2,1:cx-offsetx,5:end-5);
    Bridge(1).Callus(1).stat=regionprops3(Bridge(1).Callus(1).obj,'Volume','VoxelList');
    Bridge(1).Callus(1).size=size(Bridge(1).Callus(1).obj);
    Bridge(1).Callus(2).obj=BonePart(1).Callus.obj(cy-os2:cy+os2,cx+offsetx:end,5:end-5);
    Bridge(1).Callus(2).stat=regionprops3(Bridge(1).Callus(2).obj,'Volume','VoxelList');
    Bridge(1).Callus(2).size=size(Bridge(1).Callus(2).obj);
    Bridge(1).Callus(3).obj=BonePart(1).Callus.obj(1:cy-offsety,cx-os2:cx+os2,5:end-5);
    Bridge(1).Callus(3).stat=regionprops3(Bridge(1).Callus(3).obj,'Volume','VoxelList');
    Bridge(1).Callus(3).size=size(Bridge(1).Callus(3).obj);
    Bridge(1).Callus(4).obj=BonePart(1).Callus.obj(cy+offsety:end,cx-os2:cx+os2,5:end-5);
    Bridge(1).Callus(4).stat=regionprops3(Bridge(1).Callus(4).obj,'Volume','VoxelList');
    Bridge(1).Callus(4).size=size(Bridge(1).Callus(4).obj);
    Bridge(3).size=size(Bridge(1).Callus(1).obj);
    
    Bridge(1).Callus22(1).obj=BonePart(1).Callus.objconn(cy-os2:cy+os2,1:cx-offsetx,5:end-5)>=26;
    Bridge(1).Callus22(1).stat=regionprops3(Bridge(1).Callus22(1).obj,'Volume','VoxelList');
    Bridge(1).Callus22(1).size=size(Bridge(1).Callus22(1).obj);
    Bridge(1).Callus22(2).obj=BonePart(1).Callus.objconn(cy-os2:cy+os2,cx+offsetx:end,5:end-5)>=26;
    Bridge(1).Callus22(2).stat=regionprops3(Bridge(1).Callus22(2).obj,'Volume','VoxelList');
    Bridge(1).Callus22(2).size=size(Bridge(1).Callus22(2).obj);
    Bridge(1).Callus22(3).obj=BonePart(1).Callus.objconn(1:cy-offsety,cx-os2:cx+os2,5:end-5)>=26;
    Bridge(1).Callus22(3).stat=regionprops3(Bridge(1).Callus22(3).obj,'Volume','VoxelList');
    Bridge(1).Callus22(3).size=size(Bridge(1).Callus22(3).obj);
    Bridge(1).Callus22(4).obj=BonePart(1).Callus.objconn(cy+offsety:end,cx-os2:cx+os2,5:end-5)>=26;
    Bridge(1).Callus22(4).stat=regionprops3(Bridge(1).Callus22(4).obj,'Volume','VoxelList');
    Bridge(1).Callus22(4).size=size(Bridge(1).Callus22(4).obj);


    line=zeros(1,7);
    count=1;
    for x=1:4
        %creates imaginary plate on either end of break site
        plate1=zeros(Bridge(1).Inner(x).size);
    plate1(:,:,1:5)=1;
    plate1(:,:,end-5:end)=1;
    
    plate2=zeros(Bridge(1).Inner22(x).size);
    plate2(:,:,1:5)=1;
    plate2(:,:,end-5:end)=1;
    plate3=zeros(Bridge(1).Callus(x).size);
    plate3(:,:,1:5)=1;
    plate3(:,:,end-5:end)=1;
    plate4=zeros(Bridge(1).Callus22(x).size);
    plate4(:,:,1:5)=1; 
    plate4(:,:,end-5:end)=1;   
        
        in=0;
        in22=0;
        cal=0;
        cal22=0;
        % Add in poritons of inner and callus to determine if they span
        % fracture site. for each portion added. Volum fraction is checked.
        % must be greater than 85% to be considered in calculation
       [~,lc1]=max(Bridge(1).Inner(x).stat.Volume);
       perin=max(Bridge(1).Inner(x).stat.Volume)/sum(Bridge(1).Inner(x).stat.Volume);
        objtemp1=plate1;
            
            vox2=Bridge(1).Inner(x).stat.VoxelList{lc1,1};
             inds2=sub2ind(Bridge(1).Inner(x).size,vox2(:,2),vox2(:,1),vox2(:,3));
            objtemp1(inds2)=1;
            objtemp1=objtemp1>=1;
            
            stattemp=regionprops3(objtemp1,'Volume');
            numt=numel(stattemp.Volume);
           if(perin>.85) 
            if(numt==1)
             in=1;
            end
           end
           
         [~,lc2]=max(Bridge(1).Inner22(x).stat.Volume);
         perin22=max(Bridge(1).Inner22(x).stat.Volume)/sum(Bridge(1).Inner22(x).stat.Volume);
        objtemp2=plate2;
            
            vox2=Bridge(1).Inner22(x).stat.VoxelList{lc2,1};
             inds2=sub2ind(Bridge(1).Inner22(x).size,vox2(:,2),vox2(:,1),vox2(:,3));
            objtemp2(inds2)=1;
            objtemp2=objtemp2>=1;
            
            stattemp=regionprops3(objtemp2,'Volume');
            numt=numel(stattemp.Volume);
           if(perin22>.85) 
            if(numt==1)
             in22=1;
            end
           end
         [~,lc3]=max(Bridge(1).Callus(x).stat.Volume);
         percal=max(Bridge(1).Callus(x).stat.Volume)/sum(Bridge(1).Callus(x).stat.Volume);
        objtemp3=plate3;
            
            vox2=Bridge(1).Callus(x).stat.VoxelList{lc3,1};
             inds2=sub2ind(Bridge(1).Callus(x).size,vox2(:,2),vox2(:,1),vox2(:,3));
            objtemp3(inds2)=1;
            objtemp3=objtemp3>=1;
            
            stattemp=regionprops3(objtemp3,'Volume');
            numt=numel(stattemp.Volume);
            if(percal>.85)
            if(numt==1)
             cal=1;
            end
            end
      [~,lc4]=max(Bridge(1).Callus22(x).stat.Volume);
      percal22=max(Bridge(1).Callus22(x).stat.Volume)/sum(Bridge(1).Callus22(x).stat.Volume);
        objtemp4=plate4;
            
            vox2=Bridge(1).Callus22(x).stat.VoxelList{lc4,1};
             inds2=sub2ind(Bridge(1).Callus22(x).size,vox2(:,2),vox2(:,1),vox2(:,3));
            objtemp4(inds2)=1;
            objtemp4=objtemp4>=1;
            
            stattemp=regionprops3(objtemp4,'Volume');
            numt=numel(stattemp.Volume);
            if(percal22>.85)
            if(numt==1)
             cal22=1;
            end
            end
            
            if(in22&& cal22)
                line(1,x)=4;
            elseif(in && cal)
                line(1,x)=3;
            elseif(~in&& cal)
                line(1,x)=2;
            elseif(~in&&~cal)
                line(1,x)=1;
            else
                line(1,x)=0;
            end


    end
   line(1,5)=line(1,1)+line(1,2);
   line(1,6)=line(1,3)+line(1,4);
   line(1,7)=line(1,5)+line(1,6);
    
   
    row=[name bt line];
    
    Total=[Total; row];
    
end
app.UITable2.Data=Total;
set(app.UIFigure,'Pointer','arrow'); drawnow;
        end

        % Button pushed function: FilePathButton_7
        function FilePathButton_7Pushed(app, event)
              app.dir=[];   
           app.dir=uigetdir('C:\Users\yazan\Documents\Research\Thesis');
           app.Label_14.Text=app.dir;
           drawnow
           app.GoButton_7.Enable=1;
        end

        % Button pushed function: GoButton_7
        function GoButton_7Pushed(app, event)
set(app.UIFigure,'Pointer','watch'); drawnow;
for s_f=1:1


% Load in directory and folder name
folder = app.dir;
dirListing = dir(folder);

for d = 3:length(dirListing)
    name{d-2}=dirListing(d).name;
end

% name=sort_nat(name);
end
fN = [];
for d = 1:length(name)
fileNames = fullfile(folder,char(name(d)));
fN = strvcat(fN,fileNames);
end
 
[num len]=size(fN);

Total=[];
                    
for i=1:num
    FN=char(fN(i,:));
    load (FN)


    obj=[];
    
    if(app.CallusButton.Value==1)
        obj=BonePart.Callus.obj;
    end    
    if(app.TotalButton_3.Value==1)
        obj=BonePart.Total.obj;
    end
    if(app.CortexButton.Value==1)
        obj=BonePart.Inner.obj;
    end
    
    [Ro Co z]=size(obj(:,:,:));
    gridpos=ones(Ro,Co);
    gridact=zeros(Ro,Co);
    
    for xx=1:Ro
        for yy=1:Co
          zed=find(obj(xx,yy,:)); 
          if(app.LongestContiguousButton.Value==1)
          lengths=regionprops(obj(xx,yy,:),'Area','PixelList');%contiguous
          
          elseif(app.TotalButton.Value==1)
          lengths=regionprops(logical(zed),'Area','PixelList');%total   
          end
          maxzray=max([lengths.Area]);
          
          if(~isempty(maxzray))
          gridact(xx,yy)=maxzray;
          end 
            
            
        end
    end
    zray=gridact./z;
    name=BonePart.name;
    bone(1).zray=zray;
    bone(1).name=name;
    
    cnt=1;
    for s=.1:.1:1
    nu=0;
    nu=numel(find(zray>(s-.09) & zray<s));
    bone(1).zhist(cnt)=nu;
    cnt=cnt+1;  
    end
   savename=strcat(name,'_Zray.mat')
    save(savename,'bone')
    h=figure;
    subplot(1,2,1)
    colormap('hot')
    imagesc(bone(1).zray)
    colorbar
    
    xax=1:10:100;
    subplot(1,2,2)
    plot(xax,bone(1).zhist)
    xlabel('% span')
    ylabel('# vectors')
    savefig(h,name)
    close(h)
    
    row=[name bone(1).zhist];
    
    Total=[Total;row];
    app.UITable3.Data=Total;


end
app.UITable3.Data=Total;
set(app.UIFigure,'Pointer','arrow'); drawnow;
        end

        % Button pushed function: LoadimagesButton
        function LoadimagesButtonPushed(app, event)
app.dir=uigetdir('C:\Users\yazan\Documents\Research\Thesis');
for s_f=1:1


% Load in directory and folder name
folder = app.dir;
dirListing = dir(folder);

for d = 3:length(dirListing)
    name{d-2}=dirListing(d).name;
end

% name=sort_nat(name);
end
fN = [];
for d = 1:length(name)
fileNames = fullfile(folder,char(name(d)));
fN = strvcat(fN,fileNames);
end
 
[num len]=size(fN);
 

            for i = 1:num
             b=imread(fN(i,:));
            app.bone.slice(i).pic=b;
            
            end
            
            
            app.Slider_2.Limits=[1 num];
            
   [x y]=size(app.bone.slice(1).pic);
   
   app.EditField_9.Value=num;
   app.EditField_3.Value=1;
   app.EditField_4.Value=x;
   app.EditField_5.Value=1;
   app.EditField_6.Value=y;
   
   app.xmin =1;
   app.xmax=x;
   app.ymin=1;
   app.ymax=y;
   
   imshow(app.bone.slice(1).pic, 'Parent', app.UIAxes3)
   app.Slider_2.Enable=1; 
   app.KeepButton.Enable=1;
   app.ClearButton.Enable=1;
        end

        % Button pushed function: PreviewCropButton
        function PreviewCropButtonPushed(app, event)
   app.xmin=app.EditField_3.Value;
   app.xmax=app.EditField_4.Value;
   app.ymin=app.EditField_5.Value;
   app.ymax=app.EditField_6.Value;
   
   im=app.bone.slice(app.sl).pic(app.xmin:app.xmax,app.ymin:app.ymax);
   imshow(im, 'Parent', app.UIAxes3)
   
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = round(app.Slider_2.Value);
            app.sl=value;
            im=app.bone.slice(app.sl).pic(app.xmin:app.xmax,app.ymin:app.ymax);
            imshow(im, 'Parent', app.UIAxes3)
            app.EditField_8.Value=app.sl;
        end

        % Button pushed function: KeepButton
        function KeepButtonPushed(app, event)
            app.kept=[app.kept round(app.Slider_2.Value)];
            
            app.EditField_2.Value=num2str(app.kept);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.kept=[];
            app.EditField_2.Value=num2str(app.kept);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            num=numel(app.bone.slice);
            for x=1:num
            app.tbone.slice(x).pic=app.bone.slice(x).pic(app.xmin:app.xmax,app.ymin:app.ymax);
            app.ttbone.slice(x).pic=app.tbone.slice(x).pic>app.ThresholdEditField.Value;
            end
            
            imshow(app.ttbone.slice(app.sl).pic, 'Parent', app.UIAxes4)
            app.EdgedetectionButton.Enable=1;
            app.EditField_7.Value=app.sl;
        end

        % Button pushed function: EdgedetectionButton
        function EdgedetectionButtonPushed(app, event)
            set(app.UIFigure,'Pointer','watch'); drawnow;
            im=app.ttbone.slice(app.sl).pic;
           
            FK=app.kept;
            count=1;
          for i=1:length(FK)
              im2=app.ttbone.slice(FK(i)).pic;
              cent=[];
              prop=regionprops(im2,'Centroid');
                for x=1:numel(prop)
                 cent(:,1)=(prop(x).Centroid(:,1));
                 cent(:,2)=(prop(x).Centroid(:,2));
                end

                if(cent)
                centro(i,1)=mean(cent(:,1));
                centro(i,2)=mean(cent(:,2));
                
                count=count+1;
                end
              
            [B,L,N,A] = bwboundaries(im,8);
            
            imshow(im, 'Parent', app.UIAxes4_6)
            hold(app.UIAxes4_6, 'on')
            
           for ii = 1:length(B)
                bound=B{ii};
              
            plot(app.UIAxes4_6, bound(:,2), bound(:,1), 'w', 'LineWidth', 2)
            end 
            for ii = 1:length(B)
                sB(ii,1:2)=size(B{ii});
            end
            
            [bsize bindex]=max(sB(:,1));
            enclosed_boundaries = find(A(:,bindex));
            
            for ii = 1:length(enclosed_boundaries)
                enc=B{enclosed_boundaries(ii)};
                size_enc(ii,1:2)=size(enc);
            end
            
            
            enc_max = max(size_enc(:,1));
            enc_index = find(sB(:,1) == enc_max);
            mmm=B{enc_index};
            marrow{i}=mmm;
            m=marrow{i};
            plot(app.UIAxes4_6, m(:,2), m(:,1), 'r', 'LineWidth', 2)
            means(i,:)=[mean(m(:,2)) mean(m(:,1)) FK(i)];
            plot(app.UIAxes4_6,means(i,1),means(i,2), 'g*')
            %app.centroid(i,:)=[means(i,1),means(i,2),FK(i)];
           app.centroid(i,:)=[centro(i,1),centro(i,2),FK(i)];
            app.LinearfitButton.Enable=1;
          end
          set(app.UIFigure,'Pointer','arrow'); drawnow;
        end

        % Button pushed function: LinearfitButton
        function LinearfitButtonPushed(app, event)
            set(app.UIFigure,'Pointer','watch'); drawnow;
            x1=app.centroid(:,1);
            x2=app.centroid(:,2);
            x3=app.centroid(:,3);
            app.mx1=round(mean(x1));
            app.mx2=round(mean(x2));
            a=1:numel(app.ttbone.slice);
            
            [m n]=size(app.tbone.slice);
            for i=1:n
            front(i,:)=(app.tbone.slice(i).pic(app.mx2,:));
            side(i,:)=(app.tbone.slice(i).pic(:,app.mx1));
            
          
            end
            app.frnt=front;
            app.sde=side;
            
            app.fitobjectxz = polyfit(x3,x1,1);
            b1=polyval(app.fitobjectxz,a);
            
            
            plot(app.UIAxes4_3, x3,x1,'.')
            hold(app.UIAxes4_3, 'on')
             plot(app.UIAxes4_3, a,b1,'-')
             
            imshow(front', 'parent', app.UIAxes4_3)
            hold(app.UIAxes4_3, 'on')
            alpha(app.UIAxes4_3, 0.4)


            app.fitobjectyz = polyfit(x3,x2,1);
            b2=polyval(app.fitobjectyz,a);
            
            plot(app.UIAxes4_7, x3,x2,'.')
            hold(app.UIAxes4_7, 'on')
            plot(app.UIAxes4_7, a,b2,'-')
           imshow(side', 'Parent', app.UIAxes4_7)
            alpha(app.UIAxes4_7, 0.4)
           
            
            app.ResampleXZButton.Enable=1;
           set(app.UIFigure,'Pointer','arrow'); drawnow;
        end

        % Button pushed function: ResampleXZButton
        function ResampleXZButtonPushed(app, event)
                  set(app.UIFigure,'Pointer','watch'); drawnow;      

                        [s p]=size(app.tbone.slice(1).pic);
                        [m n]=size(app.tbone.slice);
                        
                        for j=1:s
                        for i=1:n
                        front(i,:)=(app.tbone.slice(i).pic(j,:));
                        end
                        
                       
                        ang1=(app.fitobjectxz);
                        ang1=atan(ang1(1))*180/pi;
                        zzz=imrotate(front,-ang1);
                        
                       
                        app.rotated.slice(j,1).pic=zzz;
                        end


                       im=imrotate(app.frnt,-ang1);
                          
                      imshow(im', 'Parent', app.UIAxes4_5)
                       hold(app.UIAxes4_5, 'on')


            app.ResampleYZButton.Enable=1;
            set(app.UIFigure,'Pointer','arrow'); drawnow;
        end

        % Button pushed function: ResampleYZButton
        function ResampleYZButtonPushed(app, event)
        set(app.UIFigure,'Pointer','watch'); drawnow;

                    [s p]=size(app.rotated.slice(1).pic);
                    [m n]=size(app.rotated.slice);
                    
                    for j=1:p
                    
                    for i=1:m
                    side(:,i)=(app.rotated.slice(i).pic(:,j));
                    end
                    
                  
                    ang1=(app.fitobjectyz);
                    ang1=atan(ang1(1))*180/pi;
                    zzz=imrotate(side,-ang1);
                   
                   
                    app.rotated2.slice(j).pic=zzz;
                    end
                     im=imrotate(app.sde,-ang1);
                          
                      imshow(im', 'Parent', app.UIAxes4_8)
                       hold(app.UIAxes4_8, 'on')
                    app.CreateSlicesButton.Enable=1;
        set(app.UIFigure,'Pointer','arrow'); drawnow;
        end

        % Button pushed function: CreateSlicesButton
        function CreateSlicesButtonPushed(app, event)
%            [x y z]=size(app.rotated2);
           set(app.UIFigure,'Pointer','watch'); drawnow;
                    [s p]=size(app.rotated2.slice(1).pic);
                    [m n]=size(app.rotated2.slice);
             
           for j=1:s
           
           for i=1:n
               cut(:,i)=app.rotated2.slice(i).pic(j,:);
             end  
               
               filename=sprintf('%08d', j)
               filename=[filename '.bmp'];
               imwrite(cut,filename);
           
           end 
           set(app.UIFigure,'Pointer','arrow'); drawnow;
        end

        % Button pushed function: FilePathButton_8
        function FilePathButton_8Pushed(app, event)
            app.dir=uigetdir('C:\Users\yazan\Documents\Research\Thesis');
for s_f=1:1


% Load in directory and folder name
folder = app.dir;
dirListing = dir(folder);

for d = 3:length(dirListing)
    name{d-2}=dirListing(d).name;
end

% name=sort_nat(name);
end
fN = [];
for d = 1:length(name)
fileNames = fullfile(folder,char(name(d)));
fN = strvcat(fN,fileNames);
end
 
[num len]=size(fN);
 

            for i = 1:num
             b=imread(fN(i,:));
            app.rotatedbone.slice(i).pic=b;
            
            end
            
   
    app.CalculateButton.Enable=1;


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
        set(app.UIFigure,'Pointer','watch'); drawnow;    
    app.L_lim=app.NoiseThresholdEditField.Value;
    app.U_lim=app.BoneThresholdEditField.Value;
    
    %% Calculate percent fill
 
[R C]=size(app.rotatedbone.slice(1).pic);
 
for x=1:R
    for y=1:C
        for z=1:length(app.rotatedbone.slice)
            zray(z,:)=app.rotatedbone.slice(z).pic(x,y);
        end
        
        pix_bone=find(zray > app.U_lim); %find bone pixels
        pix_tissue=find(zray > app.L_lim); %find tissue pixels
        if length(pix_tissue)~=0
            zlen=(pix_tissue(end)- pix_tissue(1))+1; %first and last positive pixel (adjusted Z-ray length)
        elseif length(pix_tissue)==0
            zlen=1;  % to avoid dividing by zero
        end

        bone(x,y)=length(pix_bone);
        total_len(x,y)=zlen; %length of tissue in zray (ignoring )
        perc(x,y)=length(pix_bone)/zlen; %percent fill
        
    end
end
  colormap(app.UIAxes9_2, 'hot')
  c=imagesc(app.UIAxes9_2,perc*100);
%   imshow(c,'parent',app.UIAxes9_2)
  hold(app.UIAxes9_2, 'on') 
  colorbar(app.UIAxes9_2)
  
   bonepix(:).full=bone;
   zray_length(:).full=total_len;
   percent(:).full=perc;
  
 
    f=percent.full;
    [xf yf]=size(f);
    full=f;
    full(full~=0)=255;
    
    [B,L,N,A] = bwboundaries(full,8);
    
    
    for ii = 1:length(B)
        sB(ii,1:2)=size(B{ii});
    end
    
    [bsize bindex]=max(sB(:,1));
    outer=B{bindex};
    
    BW_out = poly2mask(outer(:,2),outer(:,1),xf,yf);
    
    plot(app.UIAxes9_3, outer(:,2),outer(:,1), 'r', 'LineWidth', 2)
    hold(app.UIAxes9_3, 'on') 
    plot(app.UIAxes9_3, mean(outer(:,2)),mean(outer(:,1)),'.')
    
    % inner ROI
    cent1=mean(outer(:,1));
    cent2=mean(outer(:,2));
    inner(:,1)=outer(:,1)-cent1;
    inner(:,2)=outer(:,2)-cent2;
    
    width=app.roivalue;
    width=(100-width)/100;
    inner(:,1)=inner(:,1).*width;
    inner(:,2)=inner(:,2).*width;
    
    inner(:,1)=inner(:,1)+cent1;
    inner(:,2)=inner(:,2)+cent2;
    
    BW_in = poly2mask(inner(:,2),inner(:,1),xf,yf);
    
    BW_ROI=BW_out-BW_in;
    
    plot(app.UIAxes9_3, inner(:,2),inner(:,1), 'g', 'LineWidth', 2)
    
  imshow(f,'parent',app.UIAxes9_3)
  hold(app.UIAxes9_3, 'on') 

    plot(app.UIAxes9_3, outer(:,2),outer(:,1), 'r', 'LineWidth', 2)
    plot(app.UIAxes9_3, mean(outer(:,2)),mean(outer(:,1)),'.')
    plot(app.UIAxes9_3, inner(:,2),inner(:,1), 'g', 'LineWidth', 2)
    
  
    imshow(BW_ROI,'parent',app.UIAxes9_3)

  %%

BW_ROI = abs(double(BW_ROI));

area=regionprops(BW_ROI,'area');
area=area.Area;

ppp=BW_ROI.*percent.full;
ppp=ppp*100;

[s1 s2]=size(ppp);

for i=1:s2
    a=ppp(:,i);
    a=round(a*10^2)/(10^2);
    ppp(:,i)=a;
end


iter=1;
x=[];
val=[];
pr=[];

for i=iter:iter:100
    num = sum(sum(ppp>=i-(iter/2) & ppp<i+(iter/2)));
    per=(num/area)*100;
    x=[x; i];
    val=[val; num];
    pr=[pr; per];
end


plot(app.UIAxes9_4,x,val,'.', 'MarkerSize', 10)


plot(app.UIAxes9_5,x,pr,'.', 'MarkerSize', 10)


area(1:length(x),:)=area;


for i=1:100
zzz=ppp;
zzz(ppp<=i-(iter/2))=0;
zzz(ppp>i+(iter/2))=0;
BI2=zzz;
BI2(zzz>0.001)=1;
BI2=BI2.*zray_length.full;
total_t_pix(i)=sum(sum(BI2));
[row_t,col_t,v_t] = find(BI2);
avgt(i)=mean(v_t);
st_devt(i)=std(v_t);

yyy=(zzz/100).*zray_length.full;
yyy=round(yyy);
total_b_pix(i)=sum(sum(yyy));
[row_b,col_b,v_b] = find(yyy);
avgb(i)=mean(v_b);
st_devb(i)=std(v_b);
end

data=[x val area pr total_b_pix' avgb' st_devb' total_t_pix' avgt' st_devt'];
data_cells=num2cell(data);

col_header={'Percent Iteration','Number of Z-Rays','Total Selected Z-Rays','Percent of Region',...
    'Total Bone Pixels','Mean Bone','STD Bone','Total Tissue Pixels','Mean Tissue','STD Tissue'};

app.matrix_out=[col_header; data_cells];

    bonepix(:).full=bone;
    zray_length(:).full=total_len;
    percent(:).full=perc;
  app.zrayout={bonepix,zray_length,percent};
  
 app.SaveButton.Enable=1; 
 set(app.UIFigure,'Pointer','arrow'); drawnow; 
        end

        % Value changed function: RegionofinterestSlider
        function RegionofinterestSliderValueChanged(app, event)
            app.roivalue = round(app.RegionofinterestSlider.Value);
            app.EditField_10.Value=app.roivalue;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            set(app.UIFigure,'Pointer','watch'); drawnow;
            d=date;
            c=clock;
            c3=round(c(6));
            c1=num2str(c(4));
            c2=num2str(c(5));
            c3=num2str(c3);
            dd=[date '_' c1 '_' c2 '_' c3]

        xlswrite(dd,app.matrix_out)
        out=app.zrayout;
        save('percent','out')
        set(app.UIFigure,'Pointer','arrow'); drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BoneRotateTab = uitab(app.TabGroup);
            app.BoneRotateTab.Title = 'BoneRotate';
            app.LoadimagesButton = uibutton(app.BoneRotateTab, 'push');
            app.LoadimagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimagesButtonPushed, true);
            app.LoadimagesButton.Text = 'Load images';
            app.DataSizeLabel = uilabel(app.BoneRotateTab);
            app.DataSizeLabel.Text = 'Data Size';
            app.PreviewCropButton = uibutton(app.BoneRotateTab, 'push');
            app.PreviewCropButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewCropButtonPushed, true);
            app.PreviewCropButton.Text = 'Preview Crop';
            app.LoadDataButton = uibutton(app.BoneRotateTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.EdgedetectionButton = uibutton(app.BoneRotateTab, 'push');
            app.EdgedetectionButton.ButtonPushedFcn = createCallbackFcn(app, @EdgedetectionButtonPushed, true);
            app.EdgedetectionButton.Enable = 'off';
            app.EdgedetectionButton.Text = 'Edge detection';
            app.LinearfitButton = uibutton(app.BoneRotateTab, 'push');
            app.LinearfitButton.ButtonPushedFcn = createCallbackFcn(app, @LinearfitButtonPushed, true);
            app.LinearfitButton.Enable = 'off';
            app.LinearfitButton.Text = 'Linear fit';
            app.ResampleXZButton = uibutton(app.BoneRotateTab, 'push');
            app.ResampleXZButton.ButtonPushedFcn = createCallbackFcn(app, @ResampleXZButtonPushed, true);
            app.ResampleXZButton.Enable = 'off';
            app.ResampleXZButton.Text = 'Resample XZ';
            app.ResampleYZButton = uibutton(app.BoneRotateTab, 'push');
            app.ResampleYZButton.ButtonPushedFcn = createCallbackFcn(app, @ResampleYZButtonPushed, true);
            app.ResampleYZButton.Enable = 'off';
            app.ResampleYZButton.Text = 'Resample YZ';
            app.EditField_2 = uieditfield(app.BoneRotateTab, 'text');
            app.EditField_2.Editable = 'off';
            app.KeepButton = uibutton(app.BoneRotateTab, 'push');
            app.KeepButton.ButtonPushedFcn = createCallbackFcn(app, @KeepButtonPushed, true);
            app.KeepButton.Enable = 'off';
            app.KeepButton.Text = 'Keep';
            app.ClearButton = uibutton(app.BoneRotateTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Text = 'Clear';
            app.Slider_2 = uislider(app.BoneRotateTab);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Enable = 'off';
            app.EditField_3 = uieditfield(app.BoneRotateTab, 'numeric');
            app.EditField_4 = uieditfield(app.BoneRotateTab, 'numeric');
            app.EditField_5 = uieditfield(app.BoneRotateTab, 'numeric');
            app.EditField_6 = uieditfield(app.BoneRotateTab, 'numeric');
            app.ThresholdEditFieldLabel = uilabel(app.BoneRotateTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.BoneRotateTab, 'numeric');
            app.EditField_7 = uieditfield(app.BoneRotateTab, 'numeric');
            app.EditField_7.Editable = 'off';
            app.EditField_8 = uieditfield(app.BoneRotateTab, 'numeric');
            app.XminLabel = uilabel(app.BoneRotateTab);
            app.XminLabel.Text = 'Xmin';
            app.XmaxLabel = uilabel(app.BoneRotateTab);
            app.XmaxLabel.Text = 'Xmax';
            app.YminLabel = uilabel(app.BoneRotateTab);
            app.YminLabel.Text = 'Ymin';
            app.YmaxLabel = uilabel(app.BoneRotateTab);
            app.YmaxLabel.Text = 'Ymax';
            app.EditField_9 = uieditfield(app.BoneRotateTab, 'numeric');
            app.CreateSlicesButton = uibutton(app.BoneRotateTab, 'push');
            app.CreateSlicesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSlicesButtonPushed, true);
            app.CreateSlicesButton.Enable = 'off';
            app.CreateSlicesButton.Text = 'Create Slices';
            app.UIAxes3 = uiaxes(app.BoneRotateTab);
            xlabel(app.UIAxes3, 'Kept Frames')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.BoneRotateTab);
            app.UIAxes4_3 = uiaxes(app.BoneRotateTab);
            zlabel(app.UIAxes4_3, 'Z')
            app.UIAxes4_5 = uiaxes(app.BoneRotateTab);
            app.UIAxes4_6 = uiaxes(app.BoneRotateTab);
            app.UIAxes4_7 = uiaxes(app.BoneRotateTab);
            zlabel(app.UIAxes4_7, 'Z')
            app.UIAxes4_8 = uiaxes(app.BoneRotateTab);
            app.PercentfillTab = uitab(app.TabGroup);
            app.PercentfillTab.Title = 'Percentfill';
            app.FilePathButton_8 = uibutton(app.PercentfillTab, 'push');
            app.FilePathButton_8.ButtonPushedFcn = createCallbackFcn(app, @FilePathButton_8Pushed, true);
            app.FilePathButton_8.Text = 'File Path';
            app.NoiseThresholdEditFieldLabel = uilabel(app.PercentfillTab);
            app.NoiseThresholdEditFieldLabel.Text = 'Noise Threshold';
            app.NoiseThresholdEditField = uieditfield(app.PercentfillTab, 'numeric');
            app.BoneThresholdEditFieldLabel = uilabel(app.PercentfillTab);
            app.BoneThresholdEditFieldLabel.Text = 'Bone Threshold';
            app.BoneThresholdEditField = uieditfield(app.PercentfillTab, 'numeric');
            app.RegionofinterestSliderLabel = uilabel(app.PercentfillTab);
            app.RegionofinterestSliderLabel.Text = 'Region of interest';
            app.RegionofinterestSlider = uislider(app.PercentfillTab);
            app.RegionofinterestSlider.ValueChangedFcn = createCallbackFcn(app, @RegionofinterestSliderValueChanged, true);
            app.SaveButton = uibutton(app.PercentfillTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.CalculateButton = uibutton(app.PercentfillTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Text = 'Calculate';
            app.EditField_10 = uieditfield(app.PercentfillTab, 'numeric');
            app.UIAxes9_2 = uiaxes(app.PercentfillTab);
            title(app.UIAxes9_2, 'Heatmap')
            zlabel(app.UIAxes9_2, 'Z')
            app.UIAxes9_3 = uiaxes(app.PercentfillTab);
            title(app.UIAxes9_3, 'ROI')
            zlabel(app.UIAxes9_3, 'Z')
            app.UIAxes9_4 = uiaxes(app.PercentfillTab);
            title(app.UIAxes9_4, 'Number of Z-Rays vs. Percent Filled')
            xlabel(app.UIAxes9_4, 'Percent Z-Ray Fill')
            ylabel(app.UIAxes9_4, 'Number of Z-Rays')
            zlabel(app.UIAxes9_4, 'Z')
            app.UIAxes9_5 = uiaxes(app.PercentfillTab);
            title(app.UIAxes9_5, 'Percent of Z-Rays vs. Percent Filled')
            xlabel(app.UIAxes9_5, 'Percent Z-Ray Fill')
            ylabel(app.UIAxes9_5, 'Percent of Total Z-Rays')
            zlabel(app.UIAxes9_5, 'Z')
            app.getVoxTab = uitab(app.TabGroup);
            app.getVoxTab.Title = 'getVox';
            app.FilePathButton = uibutton(app.getVoxTab, 'push');
            app.FilePathButton.ButtonPushedFcn = createCallbackFcn(app, @FilePathButtonPushed, true);
            app.FilePathButton.Text = 'File Path';
            app.Label = uilabel(app.getVoxTab);
            app.Label.Text = '';
            app.SliderLabel = uilabel(app.getVoxTab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.getVoxTab);
            app.Slider.Limits = [0 255];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ofmaxgreyscaleEditFieldLabel = uilabel(app.getVoxTab);
            app.ofmaxgreyscaleEditFieldLabel.Text = '% of max grey scale';
            app.ofmaxgreyscaleEditField = uieditfield(app.getVoxTab, 'numeric');
            app.ofmaxgreyscaleEditField.ValueChangedFcn = createCallbackFcn(app, @ofmaxgreyscaleEditFieldValueChanged, true);
            app.Label_2 = uilabel(app.getVoxTab);
            app.Label_2.Text = '';
            app.ProgressLabel = uilabel(app.getVoxTab);
            app.ProgressLabel.Text = 'Progress:';
            app.GoButton = uibutton(app.getVoxTab, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Enable = 'off';
            app.GoButton.Text = 'Go';
            app.SliceSliderLabel = uilabel(app.getVoxTab);
            app.SliceSliderLabel.Enable = 'off';
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.getVoxTab);
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.Enable = 'off';
            app.NextSampleButton = uibutton(app.getVoxTab, 'push');
            app.NextSampleButton.ButtonPushedFcn = createCallbackFcn(app, @NextSampleButtonPushed, true);
            app.NextSampleButton.Enable = 'off';
            app.NextSampleButton.Text = 'Next Sample';
            app.ThresholdMethodButtonGroup = uibuttongroup(app.getVoxTab);
            app.ThresholdMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThresholdMethodButtonGroupSelectionChanged, true);
            app.ThresholdMethodButtonGroup.Title = 'Threshold Method';
            app.ValuesliderButton = uiradiobutton(app.ThresholdMethodButtonGroup);
            app.ValuesliderButton.Text = 'Value (slider)';
            app.ValuesliderButton.Value = true;
            app.bysampleButton = uiradiobutton(app.ThresholdMethodButtonGroup);
            app.bysampleButton.Text = '% (by sample)';
            app.bygroupButton = uiradiobutton(app.ThresholdMethodButtonGroup);
            app.bygroupButton.Text = '% (by group)';
            app.ThresholdButton = uibutton(app.getVoxTab, 'push');
            app.ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdButtonPushed, true);
            app.ThresholdButton.Text = 'Threshold';
            app.ThresholdValueLabel = uilabel(app.getVoxTab);
            app.ThresholdValueLabel.Text = 'Threshold Value:';
            app.Label_3 = uilabel(app.getVoxTab);
            app.Label_3.Text = '';
            app.PixelNoiseEditFieldLabel = uilabel(app.getVoxTab);
            app.PixelNoiseEditFieldLabel.Text = 'Pixel Noise';
            app.PixelNoiseEditField = uieditfield(app.getVoxTab, 'numeric');
            app.PixelNoiseEditField.ValueChangedFcn = createCallbackFcn(app, @PixelNoiseEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.getVoxTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.getVoxTab);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.getLinkTab = uitab(app.TabGroup);
            app.getLinkTab.Title = 'getLink';
            app.FilePathButton_2 = uibutton(app.getLinkTab, 'push');
            app.FilePathButton_2.ButtonPushedFcn = createCallbackFcn(app, @FilePathButton_2Pushed, true);
            app.FilePathButton_2.Text = 'File Path';
            app.Label_4 = uilabel(app.getLinkTab);
            app.Label_4.Text = '';
            app.ProgressLabel_2 = uilabel(app.getLinkTab);
            app.ProgressLabel_2.Text = 'Progress: ';
            app.GoButton_2 = uibutton(app.getLinkTab, 'push');
            app.GoButton_2.ButtonPushedFcn = createCallbackFcn(app, @GoButton_2Pushed, true);
            app.GoButton_2.Text = 'Go';
            app.Label_7 = uilabel(app.getLinkTab);
            app.Label_7.Text = '';
            app.SegBoneTab = uitab(app.TabGroup);
            app.SegBoneTab.Title = 'SegBone';
            app.FilePathButton_3 = uibutton(app.SegBoneTab, 'push');
            app.FilePathButton_3.ButtonPushedFcn = createCallbackFcn(app, @FilePathButton_3Pushed, true);
            app.FilePathButton_3.Text = 'File Path';
            app.Label_8 = uilabel(app.SegBoneTab);
            app.Label_8.Text = '';
            app.ProgressLabel_3 = uilabel(app.SegBoneTab);
            app.ProgressLabel_3.Text = 'Progress:';
            app.GoButton_3 = uibutton(app.SegBoneTab, 'push');
            app.GoButton_3.ButtonPushedFcn = createCallbackFcn(app, @GoButton_3Pushed, true);
            app.GoButton_3.Text = 'Go';
            app.Label_9 = uilabel(app.SegBoneTab);
            app.Label_9.Text = '';
            app.LinkHistTab = uitab(app.TabGroup);
            app.LinkHistTab.Title = 'Link Hist';
            app.FilePathButton_4 = uibutton(app.LinkHistTab, 'push');
            app.FilePathButton_4.ButtonPushedFcn = createCallbackFcn(app, @FilePathButton_4Pushed, true);
            app.FilePathButton_4.Text = 'File Path';
            app.Label_10 = uilabel(app.LinkHistTab);
            app.Label_10.Text = '';
            app.ProgressLabel_4 = uilabel(app.LinkHistTab);
            app.ProgressLabel_4.Text = 'Progress:';
            app.GoButton_4 = uibutton(app.LinkHistTab, 'push');
            app.GoButton_4.ButtonPushedFcn = createCallbackFcn(app, @GoButton_4Pushed, true);
            app.GoButton_4.Text = 'Go';
            app.Label_11 = uilabel(app.LinkHistTab);
            app.Label_11.Text = '';
            app.OutputLATab = uitab(app.TabGroup);
            app.OutputLATab.Title = 'OutputLA';
            app.FilePathButton_5 = uibutton(app.OutputLATab, 'push');
            app.FilePathButton_5.ButtonPushedFcn = createCallbackFcn(app, @FilePathButton_5Pushed, true);
            app.FilePathButton_5.Text = 'File Path';
            app.Label_6 = uilabel(app.OutputLATab);
            app.Label_6.Text = '';
            app.ProgressLabel_5 = uilabel(app.OutputLATab);
            app.ProgressLabel_5.Text = 'Progress:';
            app.UITable = uitable(app.OutputLATab);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.cutoffSliderLabel = uilabel(app.OutputLATab);
            app.cutoffSliderLabel.Text = ' cut off';
            app.cutoffSlider = uislider(app.OutputLATab);
            app.cutoffSlider.Limits = [1 27];
            app.cutoffSlider.ValueChangedFcn = createCallbackFcn(app, @cutoffSliderValueChanged, true);
            app.cutoffSlider.Value = 1;
            app.Label_5 = uilabel(app.OutputLATab);
            app.Label_5.Text = '';
            app.GoButton_5 = uibutton(app.OutputLATab, 'push');
            app.GoButton_5.ButtonPushedFcn = createCallbackFcn(app, @GoButton_5Pushed, true);
            app.GoButton_5.Enable = 'off';
            app.GoButton_5.Text = 'Go';
            app.Label_12 = uilabel(app.OutputLATab);
            app.Label_12.Text = '';
            app.DirectionButtonGroup = uibuttongroup(app.OutputLATab);
            app.DirectionButtonGroup.Title = 'Direction';
            app.ForwardButton = uiradiobutton(app.DirectionButtonGroup);
            app.ForwardButton.Text = 'Forward';
            app.ForwardButton.Value = true;
            app.BackwardButton = uiradiobutton(app.DirectionButtonGroup);
            app.BackwardButton.Text = 'Backward';
            app.getRUSTTab = uitab(app.TabGroup);
            app.getRUSTTab.Title = 'getRUST';
            app.FilePathButton_6 = uibutton(app.getRUSTTab, 'push');
            app.FilePathButton_6.ButtonPushedFcn = createCallbackFcn(app, @FilePathButton_6Pushed, true);
            app.FilePathButton_6.Text = 'File Path';
            app.Label_13 = uilabel(app.getRUSTTab);
            app.Label_13.Text = '';
            app.UITable2 = uitable(app.getRUSTTab);
            app.UITable2.ColumnName = {'Name'; 'Break Type'; 'Cort 1'; 'Cort 2'; 'Cort 3'; 'Cort 4'; 'View 1'; 'View 2'; 'Total'};
            app.UITable2.RowName = {};
            app.GoButton_6 = uibutton(app.getRUSTTab, 'push');
            app.GoButton_6.ButtonPushedFcn = createCallbackFcn(app, @GoButton_6Pushed, true);
            app.GoButton_6.Enable = 'off';
            app.GoButton_6.Text = 'Go';
            app.getZrayTab = uitab(app.TabGroup);
            app.getZrayTab.Title = 'getZray';
            app.FilePathButton_7 = uibutton(app.getZrayTab, 'push');
            app.FilePathButton_7.ButtonPushedFcn = createCallbackFcn(app, @FilePathButton_7Pushed, true);
            app.FilePathButton_7.Text = 'File Path';
            app.Label_14 = uilabel(app.getZrayTab);
            app.Label_14.Text = '';
            app.ZRayMethodButtonGroup = uibuttongroup(app.getZrayTab);
            app.ZRayMethodButtonGroup.Title = 'Z-Ray Method';
            app.LongestContiguousButton = uiradiobutton(app.ZRayMethodButtonGroup);
            app.LongestContiguousButton.Text = 'Longest Contiguous';
            app.LongestContiguousButton.Value = true;
            app.TotalButton = uiradiobutton(app.ZRayMethodButtonGroup);
            app.TotalButton.Text = 'Total';
            app.RegionofInterestButtonGroup = uibuttongroup(app.getZrayTab);
            app.RegionofInterestButtonGroup.Title = 'Region of Interest';
            app.TotalButton_3 = uiradiobutton(app.RegionofInterestButtonGroup);
            app.TotalButton_3.Text = 'Total';
            app.TotalButton_3.Value = true;
            app.CallusButton = uiradiobutton(app.RegionofInterestButtonGroup);
            app.CallusButton.Text = 'Callus';
            app.CortexButton = uiradiobutton(app.RegionofInterestButtonGroup);
            app.CortexButton.Text = 'Cortex';
            app.GoButton_7 = uibutton(app.getZrayTab, 'push');
            app.GoButton_7.ButtonPushedFcn = createCallbackFcn(app, @GoButton_7Pushed, true);
            app.GoButton_7.Enable = 'off';
            app.GoButton_7.Text = 'Go';
            app.UITable3 = uitable(app.getZrayTab);
            app.UITable3.ColumnName = {'Name'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.UITable3.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LinkAN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end